classdef CellSegmentationApp_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RadiusEditField          matlab.ui.control.NumericEditField
        RadiusEditFieldLabel     matlab.ui.control.Label
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        NewCellButton            matlab.ui.control.Button
        WindowGreenSlider        matlab.ui.control.Slider
        WindowGreenSliderLabel   matlab.ui.control.Label
        CenterGreenSlider        matlab.ui.control.Slider
        CenterGreenSliderLabel   matlab.ui.control.Label
        WindowRedSlider          matlab.ui.control.Slider
        WindowRedSliderLabel     matlab.ui.control.Label
        CenterRedSlider          matlab.ui.control.Slider
        CenterRedSliderLabel     matlab.ui.control.Label
        ThresholdGreenKnob       matlab.ui.control.Knob
        ThresholdGreenKnobLabel  matlab.ui.control.Label
        ThresholdRedKnob         matlab.ui.control.Knob
        ThresholdRedKnobLabel    matlab.ui.control.Label
        CloseButton              matlab.ui.control.Button
        RadiusKnob               matlab.ui.control.Knob
        RadiusKnobLabel          matlab.ui.control.Label
        SliceSlider              matlab.ui.control.Slider
        SliceSliderLabel         matlab.ui.control.Label
        CellsStatsButton         matlab.ui.control.Button
        CylinderButton           matlab.ui.control.Button
        EndButton                matlab.ui.control.Button
        SegmentationButton       matlab.ui.control.Button
        ArterySelectionButton    matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        DatasetDropDown          matlab.ui.control.DropDown
        DatasetDropDownLabel     matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CurrentData=struct('Directory',{''},... Directory with the data 
                           'FileSaved',{''},... File where data is saved "*_CSGui.mat"
                           'FileGreenChanel', {''},... Directroy and file of the green data
                           'FileRedChanel',{''},... Directory and file of the red data
                           'DataGreenChanel',[],... Data from the green Chanel
                           'DataRedChanel',[],... Data for the red Chanel
                           'DataGreenChanel_Artery',[],... ROI around the artery Green
                           'DataRedChanel_Artery',[],... ROI arodn the artery Red
                           'Alpha',[],... Alpha variable for the projection of points
                           'ROIartery',[], ... 4 number upper left corner (x,y) width and height of ROI of artery 
                           'Centers',[],... Centers of the lumen
                           'Walls',[],... Walls of ht ecells
                           'ROI',[],... ROIs of the cells defined in the 2D projections
                           'Flags',struct('MustIConsiderCellsOnEdge',1,'MustIConsiderEdge',1,'IsSaved',0,'IamHappyWithRadious',0),...
                           'Polygon',[],...
                           'Area',[],...
                           'Resolution',[1 1 1],...
                           'Temp',[]);   % Ths is a 
    end
    
    properties (Access = private)
        Version='Ver1.0.01' % Description
        VersionDate='10.29.2021' % Description
    end
    
    methods (Access = private)
        
        function app = SaveData(app)
               if  app.CurrentData.Flags.IsSaved==0 
                    % If not ask if you want to save it
                    answer = questdlg('Save current data?','Save data', ...
                        	'Yes','No','Yes');
                    if strcmp(answer,'Yes')
                        % Ask for the name
                        if isempty(app.CurrentData.FileSaved)
                            I=strfind(app.CurrentData.FileGreenChanel,filesep);
                            answer = inputdlg('Enter a File Name or use default','File Name',[1 100],{app.CurrentData.FileGreenChanel(I(end)+4:end-4)}); 
                            if isequal(answer,0)
                                answer=app.CurrentData.FileGreenChanel(I(end)+1:end-4);
                            end
                            app.CurrentData.FileSaved=[answer{1} '_CSGui.mat'];
                        end
                        app.CurrentData.Flags.IsSaved=1;
                       % Save the app file data in the struct Values
                        Values=struct('CurrentData',app.CurrentData,...
                                      'ThresholdGreenKnob',app.ThresholdGreenKnob.Value,...
                                      'ThresholdRedKnob',app.ThresholdRedKnob.Value,...
                                      'RadiusKnob',app.RadiusKnob.Value,...
                                      'SliceSlider',app.SliceSlider.Value,...
                                      'WindowGreenSlider',app.WindowGreenSlider.Value,...
                                      'CenterGreenSlider',app.CenterGreenSlider.Value,...
                                      'WindowRedSlider',app.WindowRedSlider.Value,...
                                      'CenterRedSlider',app.CenterRedSlider.Value);
                        
                        save([app.CurrentData.Directory  app.CurrentData.FileSaved],'Values')
                        % Update the flag of being saved                        
                    end
              end
        end
        
        function app = UpdateAppFromFile(app,File)
            if exist(File,'file')
                load(File,'Values');
                [Directory, ~, ~] = fileparts(File);
                app.CurrentData=Values.CurrentData;
                app.CurrentData.Directory = Directory;
                app.ThresholdGreenKnob.Value=Values.ThresholdGreenKnob;
                app.ThresholdRedKnob.Value=Values.ThresholdRedKnob;
                app.RadiusKnob.Value=Values.RadiusKnob;
                app.SliceSlider.Value=Values.SliceSlider;
                app.WindowGreenSlider.Value=Values.WindowGreenSlider;
                app.CenterGreenSlider.Value=Values.CenterGreenSlider;
                app.WindowRedSlider.Value=Values.WindowRedSlider;
                app.CenterRedSlider.Value=Values.CenterRedSlider; 
            end
        end
        
        function DisplayRGBimage(app,varargin)
            % The sliders are between 0 and 100 and need to be compensated
            % in 0 to maxint. Tif is uint12, so values go from 0 to 65,535
            MaxInt=double(uint16(1e34));
            switch nargin
                case 1
                    CR=app.CenterRedSlider.Value/100*MaxInt;
                    WR=app.WindowRedSlider.Value/100*MaxInt;
                    CG=app.CenterGreenSlider.Value/100*MaxInt;
                    WG=app.WindowGreenSlider.Value/100*MaxInt;
                    Sl=round(app.SliceSlider.Value);
                case 2
                    CR=varargin{1}(1)/100*MaxInt;
                    WR=varargin{1}(2)/100*MaxInt;
                    CG=varargin{1}(3)/100*MaxInt;
                    WG=varargin{1}(4)/100*MaxInt;
                    Sl=max(1,min(size(app.CurrentData.DataRedChanel,3),round(varargin{1}(5))));
                otherwise
                    disp('Error in DisplayRGBimage too many input arguments')
            end
            MinGreen=max(0,CG-round(WG/2));
            MaxGreen=min(MaxInt,CG+round(WG/2));
            MinRed=max(0,CR-round(WR/2));
            MaxRed=min(MaxInt,CR+round(WR/2));
            if isempty(app.CurrentData.DataGreenChanel_Artery)
                Image=cat(3, ...
                    max(0,min(1,(double(app.CurrentData.DataRedChanel(:,:,Sl))-MinRed)/(MaxRed-MinRed))), cat(3,...
                    max(0,min(1,(double(app.CurrentData.DataGreenChanel(:,:,Sl))-MinGreen)/(MaxGreen-MinGreen))), ...
                    zeros(size(app.CurrentData.DataGreenChanel(:,:,Sl)))));
            else
                Image=cat(3, ...
                    max(0,min(1,(double(app.CurrentData.DataRedChanel_Artery(:,:,Sl))-MinRed)/(MaxRed-MinRed))), cat(3,...
                    max(0,min(1,(double(app.CurrentData.DataGreenChanel_Artery(:,:,Sl))-MinGreen)/(MaxGreen-MinGreen))), ...
                    zeros(size(app.CurrentData.DataGreenChanel_Artery(:,:,Sl)))));
            end
            
            if isempty(getimage(app.UIAxes))|| ~isempty(app.CurrentData.Centers) 
                % There is no image saved use the imagesc or you are going
                % to plot the centers and radious, in such case you need to
                % update the axis everytime
                cla(app.UIAxes)
                imagesc(app.UIAxes,Image);
                if ~isempty(app.CurrentData.Centers)
                    hold(app.UIAxes,'on')
                    if ~isempty(find(app.CurrentData.Centers(:,1)==Sl,1))
                        Circle=[app.CurrentData.Centers(app.CurrentData.Centers(:,1)==Sl,3)+app.RadiusKnob.Value*cos(2*pi*(0:200)'/200),app.CurrentData.Centers(app.CurrentData.Centers(:,1)==Sl,2)+app.RadiusKnob.Value*sin(2*pi*(0:200)'/200)];
                        I=find(min(Circle,[],2)>0&Circle(:,1)<=size(app.CurrentData.DataGreenChanel_Artery,2)+1&Circle(:,2)<=size(app.CurrentData.DataGreenChanel_Artery,1)+1);
                        Circle=Circle(I,:);
                        if sum(abs(Circle(1,:)-Circle(end,:)))
                            Circle=[Circle;Circle(1,:)];
                        end
                        plot(app.UIAxes,app.CurrentData.Centers(app.CurrentData.Centers(:,1)==Sl,3),app.CurrentData.Centers(app.CurrentData.Centers(:,1)==Sl,2),'ob',Circle(:,1),Circle(:,2),'-b','MarkerSize',4,'LineWidth',3)
                        for ij=1:length(app.CurrentData.Polygon{Sl,1})
                            plot(app.UIAxes,app.CurrentData.Polygon{Sl,1}{ij}(:,1),app.CurrentData.Polygon{Sl,1}{ij}(:,2),'w')
                        end
                    end        
                end
                axis(app.UIAxes,'off');
            else % If there is already an image just update the image and not change the axis so that all properties can be detetected
                L=length(app.UIAxes.Children);
                count=1;
                while count<=L
                    try
                        app.UIAxes.Children(count).CData=Image;
                        count=count+L; % Break the loop
                    catch 
                        count=count+1;
                    end
                end
                
            end
        end
        
        function app = CalculateCentersAndSegmentation(app)
            % Red chanel image
            Filter=1/16*[1 2 1;2 4 2;1 2 1]; % Gaussian filter
            %% NEW ADDD average over slices
            Aux=app.CurrentData.DataRedChanel_Artery;
            AuxB=Aux;
            Gap=16;
            n=max(1,floor((Gap-1)/2));
            for i_sl=1:size(Aux,3)
                Aux(:,:,i_sl)=mean(AuxB(:,:,max(1,i_sl-n):min(size(Aux,3),i_sl+n)),3);
            end
            
            IMArf = Aux;%imfilter(double(Aux),Filter);

            Mask=uint8(IMArf>app.ThresholdRedKnob.Value*1e3);
            % Selection the largest connected dataset which should be the artery
            CP=bwconncomp(Mask);
            
            ObjSize=zeros(CP.NumObjects,1);
            for i=1:CP.NumObjects
                ObjSize(i)=length(CP.PixelIdxList{i});
            end               
            ObjA=ObjSize==max(ObjSize);
            % Redifine the mask to include only the largest dataset
            Mask(:)=0;
            Mask(CP.PixelIdxList{ObjA})=1;
            app.CurrentData.MaskRed=Mask;
            
            %% Now we try to reduce the sections   
            MinimumLength=1;
            L=0.5;
            Spoly=cell(size(IMArf,3),1);
            Sarea=cell(size(IMArf,3),1);
            %Vertex=[1 1;size(IMArf,1) 1;size(IMArf,1) size(IMArf,2); 1 size(IMArf,2)];
            MustIConsiderCellsOnEdge=0;
            
            for isl=1:size(IMArf,3)
                Mat=double(Mask(:,:,isl));
                Mat([1,end],:)=0;% To set boders to zero
                Mat(:,[1,end])=0;
                if max(Mat(:))>0
                    c=contourc(imfilter(imfilter(Mat,Filter),Filter),1);%[.5, .5]);
                    count=1;
                    while count<size(c,2)
                        if c(2,count)>MinimumLength
                            Polygon=c(:,count+1:count+c(2,count))';
                            if abs(Polygon(1,1)-Polygon(end,1))+abs(Polygon(1,2)-Polygon(end,2))==0
                                %If it is a polygone
                                try
                                Spoly{isl,L==c(1,count)}{end+1}=[Polygon isl*ones(size(Polygon,1),1)];
                                Sarea{isl,L==c(1,count)}=[Sarea{L==c(1,count)};[mean(Spoly{isl,L==c(1,count)}{end}),...
                                    polyarea(Spoly{isl,L==c(1,count)}{end}(:,1),Spoly{isl,L==c(1,count)}{end}(:,2))]];
                                catch
                                    disp('HJhjhjhjhj')
                                end
                            elseif MustIConsiderCellsOnEdge
                                if abs(Polygon(1,1)-Polygon(end,1))==0||abs(Polygon(1,2)-Polygon(end,2))==0
                                    % In this case we are in an edge and close it
                                    Polygon=[Polygon;Polygon(1,:)];
                                    Spoly{isl,L==c(1,count)}{end+1}=[Polygon isl*ones(size(Polygon,1),1)];
                                    Sarea{isl,L==c(1,count)}=[Sarea{isl,L==c(1,count)};[mean(Spoly{isl,L==c(1,count)}{end}),...
                                        polyarea(Spoly{isl,L==c(1,count)}{end}(:,1),Spoly{isl,L==c(1,count)}{end}(:,2))]];
                                end
                            end
                        end
                        count=count+c(2,count)+1;
                    end
                end
            end
            app.CurrentData.Polygon=Spoly;
            app.CurrentData.Area=Sarea;
            
            % Calculate the center of the ROIs
            % We start by identifying the center of each lumen
            Centers=[];
            for i=1:size(Mask,3)
                
                MustIUseNew=1;
                if MustIUseNew
                    CPa=bwconncomp(1-imfilter(imfilter(double(Mask(:,:,i)),Filter),Filter)>0); % This wll be zero for red and ones for background
                    Size=[]; % Remove all small holes since theiy are not relevant
                    for ic=1:CPa.NumObjects
                        Size(ic)=length(CPa.PixelIdxList{ic});
                    end
                    CPa.NumObjects=sum(Size>50);% 50 is n ad hoc variables 
                    CPa.PixelIdxList=CPa.PixelIdxList(Size>50);
                    if i==230
                        disp('STOPPPP')                       
                    end
                    AuxB=Mask(:,:,i);
                    [Xm,Ym]=find(AuxB>0);
                    M1=sqrt(mean((Xm-mean(Xm)).^2));
                    M2=sqrt(mean((Ym-mean(Ym)).^2));
                    Xm=mean(Xm);
                    Ym=mean(Ym);
                    if CPa.NumObjects==1
                        % If only one number use the CM of the ed signal
                        Centers=[Centers;[i Xm Ym]];
                    else  
                        % If more than one calculate the centere of
                        % each block
                        Aux=zeros(CPa.NumObjects,2);
                        for i_c=1:CPa.NumObjects
                            [XL,YL]=ind2sub(CPa.ImageSize,CPa.PixelIdxList{i_c});
                            Aux(i_c,:)=[mean(XL),mean(YL)];
                        end
                        % There is the cases where only 2 peacies are found and the wall is borken. 
                        % To avoid this I will use the Momentums M1 and M2
                        J=abs(Aux(:,1)-Xm);
                        K=abs(Aux(:,2)-Ym);
                        L=find(J<M1&K<M2);
                        switch length(L)
                            case 0
                                % If none are within the distance to the CM
                                % use the CM
                                Centers=[Centers;[i Xm Ym]];
                            case 1
                                I=sqrt(sum((Aux-repmat([Xm Ym],[CPa.NumObjects 1])).^2,2));
                                Centers=[Centers;[i Aux(find(I==min(I),1,'last'),:)]];
                            otherwise
                                % IN such case select the one with the
                                % lowest area
                                I=1e10*ones(1,CPa.NumObjects);
                                for ij=1:CPa.NumObjects
                                    I(ij)=length(CPa.PixelIdxList{ij});
                                end
                                H=find(I(L)==min(I(L)),1,'last');
                                Centers=[Centers;[i Aux(L(H),:)]];
                        end                        
                    end
                else
                    CPa=bwconncomp(1-Mask(:,:,i)); % This wll be zero for red and ones for background
                    Size=[]; % Remove all small holes since theiy are not relevant
                    for ic=1:CPa.NumObjects
                        Size(ic)=length(CPa.PixelIdxList{ic});
                    end
                    CPa.NumObjects=sum(Size>50);% 50 is n ad hoc variables 
                    CPa.PixelIdxList=CPa.PixelIdxList(Size>50);
                    switch CPa.NumObjects
                        case 2
                            % In this case the lumen should be the shortest one
                            if length(CPa.PixelIdxList{1})>length(CPa.PixelIdxList{2})
                                % Then 2 is the lumen calculate the center
                                [XL,YL]=ind2sub(CPa.ImageSize,CPa.PixelIdxList{2});
                            else
                                % Then 2 is the lumen calculate the center
                                [XL,YL]=ind2sub(CPa.ImageSize,CPa.PixelIdxList{1});
                            end  
                            % Use the closes to the CM
                            
                            
                            Centers=[Centers;[i mean(XL),mean(YL)]];
                        case 1
                            %disp('One Case')
                            if ~isempty(Centers)
                                Centers=[Centers; [i Centers(end,2:3)]];
                            end
                            % TBD
                        otherwise
                            if ~isempty(Centers) % This requries the Centers not beoung empty. Otherwise we will have to think
                                % If there is more than one select the center closest to the
                                % latest coordidates
                                Size=[];
                                for ic=1:CPa.NumObjects
                                    Size(ic)=length(CPa.PixelIdxList{ic});
                                end
                                % for all but the largest one calculate the Center
                                CentersAux=[];
                                for ic=1:CPa.NumObjects
                                    if length(CPa.PixelIdxList{ic})<max(Size) % If not the maximum find the center
                                        [XLaux,YLaux]=ind2sub(CPa.ImageSize,CPa.PixelIdxList{ic});
                                        CentersAux=[CentersAux;[mean(XLaux),mean(YLaux)]];
                                    end
                                end
                                % Finally find the CentersAux closesst to the latest center
                                % in the figure
                                Dist=sqrt(sum((CentersAux-repmat(Centers(end,2:3),[CPa.NumObjects-1 1])).^2,2));
                                Centers=[Centers;[i CentersAux(find(Dist==min(Dist),1,'Last'),:)]];
                            end
                    end
                end
            end
            
            % Identify those outliers to the displacement. By analyzing the data I have
            % obserbed that the Frame Displacement is bellow 1.5 for 
            FrameDisplacement=sqrt((Centers(2:end,2)-Centers(1:end-1,2)).^2+(Centers(2:end,3)-Centers(1:end-1,3)).^2);
            disp(['Number of slices with discplacement over 2.5 voxels between adjacent slices =' blanks(1) num2str(sum(FrameDisplacement>2.5)) ])

            if sum(FrameDisplacement>2.5)>10000 %2.5 is an ad-hoc threshold based on data analysis of the variation of center
                % Find the error centers and correct
                I=find(FrameDisplacement>2.5);
                if I(1)==1
                    disp('WIP CylinderButtonPushed LINE: if sum(FrameDisplacement>2.5)>0')
                else
                    % Correct this still NEEDS TESTING!!!
                    while ~isempty(I)
                        if length(I)>1
                            if I(2)==I(1)+1
                                % If consecutive we are dealing with a
                                % sngle slice
                                Centers(I(1),2)=Centers(I(1)-1,2)+(Centers(I(1)+1,2)-Centers(I(1)-1,2))/2;
                                Centers(I(1),3)=Centers(I(1)-1,3)+(Centers(I(1)+1,3)-Centers(I(1)-1,3))/2;
                                
                            else % In this case we are dealing with a set of values
                                Centers(I(1):I(2),2)=Centers(I(1)-1,2)+(Centers(I(2)+1,2)-Centers(I(1)-1,2))/(I(2)-I(1)+2)*(1:(I(2)-I(1)+1));
                                Centers(I(1):I(2),3)=Centers(I(1)-1,3)+(Centers(I(2)+1,3)-Centers(I(1)-1,3))/(I(2)-I(1)+2)*(1:(I(2)-I(1)+1));                                
                            end
                        else
                            Centers(end,2)=Centers(end-1,2);
                            Centers(end,3)=Centers(end-1,3);                                
                        end
                        % Now shorten the variable I
                        if length(I)>2
                            I=I(3:end);
                        else
                            I=[];
                        end
                    end
                end
            end
            FrameDisplacement=sqrt((Centers(2:end,2)-Centers(1:end-1,2)).^2+(Centers(2:end,3)-Centers(1:end-1,3)).^2);
            disp(['Number of slices with discplacement over 2.5 voxels between adjacent slices =' blanks(1) num2str(sum(FrameDisplacement>2.5)) ])
            app.CurrentData.Centers=Centers;

            
        end
        
        function app = CalculateProjectionInCylinder(app)
            IMAgf=app.CurrentData.DataGreenChanel_Artery.*uint16(app.CurrentData.MaskRed);
            MaskG=IMAgf>app.ThresholdGreenKnob.Value*1e3;
            Alpha=[];
            Centers=app.CurrentData.Centers;
            for i=1:size(app.CurrentData.MaskRed,3)
                %imagesc(MaskG(:,:,i))
                %hold on
                ii=find(Centers(:,1)==i);
                if ~isempty(ii)                    
                    [I,J]=find(MaskG(:,:,i)>0);
                    K=find(sqrt((I-Centers(ii,2)).^2+(J-Centers(ii,3)).^2)<app.RadiusKnob.Value); % Those voxels within the radiosu
                    SI=sign(I(K)-Centers(ii,2));
                    SJ=sign(J(K)-Centers(ii,3));
                    Aux=atan(abs(J(K)-Centers(ii,3))./abs(I(K)-Centers(ii,2)));
                    L=find(SI==1&SJ==-1);
                    if ~isempty(L)
                        Aux(L)=2*pi-Aux(L);
                    end
                    L=find(SI==-1&SJ==-1);
                    if ~isempty(L)
                        Aux(L)=pi+Aux(L);
                    end
                    L=find(SI==-1&SJ==1);
                    if ~isempty(L)
                        Aux(L)=pi-Aux(L);
                    end
                    Alpha=[Alpha;[Aux i*ones(size(Aux)) I(K) J(K)]];
                end
            end
           app.CurrentData.Alpha=Alpha; 
        end
        
        function PlotAlphaAndFinishedROIs(app)
            if ~isempty(app.CurrentData.Alpha)
                app.CurrentData.Temp{1}=figure;
                axis on
                app.CurrentData.Temp{2}=gca;
                plot(app.CurrentData.Temp{2},app.CurrentData.Alpha(:,1),app.CurrentData.Alpha(:,2),'.',app.CurrentData.Alpha(:,1)+2*pi,app.CurrentData.Alpha(:,2),'.b')
                axis(app.CurrentData.Temp{2},'on')
                hold(app.CurrentData.Temp{2},'on')
                title(['Threshold =' blanks(1) num2str(app.ThresholdGreenKnob.Value) blanks(1) 'PRESS A KEY TO START A CELL SEGMENTATION'])
                if ~isempty(app.CurrentData.ROI)
                    for i_r=1:length(app.CurrentData.ROI)
                        if ~isempty(app.CurrentData.ROI{i_r})
                            plot(app.CurrentData.Temp{2},app.CurrentData.ROI{i_r}(:,1),app.CurrentData.ROI{i_r}(:,2),'o-','LineWidth',2,'Color',rand(1,3))
                        end
                    end
                end
                xlabel('Angle')
                ylabel('Slice')
            end
        end
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DatasetDropDown
        function DatasetDropDownOpening(app, event)
            if strcmp(app.DatasetDropDown.Value,'Load new image')
                
                
                % First check if you need to save the images
                if length(app.CurrentData.Directory)>2    
                    app=SaveData(app);
                end
                
                % Then llok for new directories and files
                if strcmp(filesep,'/') % If linux look for data in temp
                    root='/tmp/';
                else
                    root='C:\Windows\Temp\';
                end
                
                % Look if you have memory of where you were working
                if exist([root 'DirectoryGUICellData.mat'],'file')
                    load([root 'DirectoryGUICellData.mat'],'Directory');
                    if ~exist(Directory,'dir')
                        Directory='./';
                    end
                else
                    Directory='./';
                end
                
                
                % Ask for the directory where the data is saved
                if isfolder(Directory)
                    Directory=uigetdir(Directory,'Select directory with your images');
                else
                    Directory=uigetdir('./','Select directory with your images');
                end
                
                if ~strcmp(Directory(end),filesep)
                    Directory=[Directory filesep];
                end
                
                % Once you get the Directory save the directory
                save([root 'DirectoryGUICellData.mat'],'Directory');
                
                % Check if exist a matlab file with data please load the dataset 
                if isempty(dir([Directory '*CSGui.mat']))
                    IsNew=true;
                else
                    File=uigetfile([Directory '*_CSGui.mat'],'Select file you want to work'); 
                    if isequal(File,0) || isequal(Directory,0)
                        IsNew=true; % If none is selected open a new one
                    else
                        IsNew=false;
                    end                       
                end
                
                if ~IsNew % i..e. if a dataset has been identifed
                    % If you have a dataset open that s not saved ask if you want to save it
                    % Load the app the app....
                    app=UpdateAppFromFile(app,[Directory File]);
                else                   
                    % Initialize the data for the new Subject
                    app.CurrentData.Directory=Directory;
                    app.CurrentData.FileSaved=''; % Str with File Name
                    [FileG,PathG]=uigetfile('*.tif;*.tiff;*.TIF','Select Green Chanel',Directory);
                    app.CurrentData.FileGreenChanel=[PathG FileG];% Str File Name
                    [FileR,PathR]=uigetfile('*.tif;*.tiff;*.TIF','Select Green Chanel',PathG);
                    app.CurrentData.FileRedChanel=[PathR FileR];% Str with file name 
                    app.CurrentData.DataGreenChanel=permute(tiffreadVolume([PathG FileG]),[1 3 2]); % Dataset
                    app.CurrentData.DataRedChanel=permute(tiffreadVolume([PathR FileR]),[1 3 2]); % Dataset
                    app.CurrentData.DataGreenChanel_Artery=[];
                    app.CurrentData.DataRedChanel_Artery=[];
                    app.CurrentData.Alpha=[];       % Variable used for the projection
                    app.CurrentData.ROIartery=[];   % Ths is a 4 number upper left corner (x,y) width and height
                    app.CurrentData.Centers=[];
                    app.CurrentData.Walls=[];
                    app.CurrentData.MaskGreen=[];
                    app.CurrentData.MaskRed=[];
                    app.CurrentData.ROI=[];
                    app.CurrentData.Flags.MustIConsiderCellsOnEdge=1;
                    app.CurrentData.Flags.MustIConsiderEdge=1;
                    app.CurrentData.Flags.IsSaved=0; % Flag
                    app.CurrentData.Polygon=[]; 
                    app.CurrentData.Temp=[];
                    app.CurrentData.Resolution=[1 1 1];
                    app = SaveData(app);
                end 

                app.DatasetDropDown.Items{2}=app.CurrentData.FileSaved;
                app.DatasetDropDown.Value=app.CurrentData.FileSaved;
                app.UIAxes.Visible='on';
                % Show the slider and update the values and ticks
                app.SliceSlider.Visible='on';
                app.SliceSlider.Limits=[1,size(app.CurrentData.DataRedChanel,3)];
                app.SliceSlider.MajorTicks=round(1:size(app.CurrentData.DataRedChanel,3)/10:size(app.CurrentData.DataRedChanel,3));
                app.SliceSlider.Value=round(size(app.CurrentData.DataRedChanel,3)/2);
                %app.SliceSlider.MajorTicksLabels=numround(1:size(app.CurrentData.DataRedChanel,3)/10:size(app.CurrentData.DataRedChanel,3))
                DisplayRGBimage(app);
                
                % Show the image display bars
                app.CenterGreenSlider.Visible='on';
                app.CenterGreenSliderLabel.Visible='on';
                app.CenterRedSlider.Visible='on';
                app.CenterRedSliderLabel.Visible='on';
                app.WindowGreenSlider.Visible='on';
                app.WindowGreenSliderLabel.Visible='on';
                app.WindowRedSlider.Visible='on';
                app.WindowRedSliderLabel.Visible='on';
                
                % Show Save buttom
                app.SaveButton.Visible='on';
                app.CloseButton.Visible='on';
                app.ArterySelectionButton.Visible='on';
                if ~isempty(app.CurrentData.DataGreenChanel_Artery)
                    app.CylinderButton.Visible='on';
                else
                    app.CylinderButton.Visible='off';
                end

                if app.CurrentData.Flags.IamHappyWithRadious==1
                    app.SegmentationButton.Visible='on';
                else
                    app.SegmentationButton.Visible='off';
                end

                if ~isempty(app.CurrentData.ROI)
                    app.CellsStatsButton.Visible='on';
                else
                    app.CellsStatsButton.Visible='off';
                end
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Check if the dataset has been saved
            app = SaveData(app);
            delete(app)
            
        end

        % Value changing function: CenterRedSlider
        function CenterRedSliderValueChanging(app, event)
            app.CenterRedSlider.Value=event.Value;
            %DisplayRGBimage(app,double([app.CenterRedSlider.Value app.WindowRedSlider.Value app.CenterGreenSlider.Value app.WindowGreenSlider.Value round(app.SliceSlider.Value)]));
            DisplayRGBimage(app);
            app.CurrentData.Flags.IsSaved=0;
        end

        % Value changing function: WindowRedSlider
        function WindowRedSliderValueChanging(app, event)
            app.WindowRedSlider.Value = event.Value;
            DisplayRGBimage(app);
            app.CurrentData.Flags.IsSaved=0;
        end

        % Value changing function: CenterGreenSlider
        function CenterGreenSliderValueChanging(app, event)
            app.CenterGreenSlider.Value = event.Value;
            DisplayRGBimage(app);
            app.CurrentData.Flags.IsSaved=0;
        end

        % Value changing function: WindowGreenSlider
        function WindowGreenSliderValueChanging(app, event)
            app.WindowGreenSlider.Value = event.Value;
            DisplayRGBimage(app);
            app.CurrentData.Flags.IsSaved=0;
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            %SL = round(event.Value);
            app.SliceSlider.Value=round(event.Value);
            DisplayRGBimage(app);
            app.CurrentData.Flags.IsSaved=0;            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            SaveData(app);
        end

        % Button pushed function: ArterySelectionButton
        function ArterySelectionButtonPushed(app, event)
            % This function will identify a rectangle
            % First if there is already an area ask if you want to proceed
            % and delete all the progresss.
            answer = 'Yes';
            if ~isempty(app.CurrentData.Centers) % If cylinders have been defined, then warn that this may caluse deletion of the ROIs
                answer = questdlg(sprintf('Do you want to select a new area?\nThis WILL DELETE any previous segmentation'),'WARNING', ...
                    	'Yes','No','Yes');
                if strcmp(answer,'Yes')
                    % Then delete all progress 
                    app.CurrentData.DataGreenChanel_Artery=[];
                    app.CurrentData.DataRedChanel_Artery=[];
                    app.CurrentData.Alpha=[];       % Variable used for the projection
                    app.CurrentData.ROIartery=[];   % Ths is a 4 number upper left corner (x,y) width and height
                    app.CurrentData.Centers=[];
                    app.CurrentData.Walls=[];
                    app.CurrentData.MaskGreen=[];
                    app.CurrentData.MaskRed=[];
                    app.CurrentData.ROI=[];
                    app.CurrentData.Flags.MustIConsiderCellsOnEdge=1;
                    app.CurrentData.Flags.MustIConsiderEdge=1;
                    app.CurrentData.Flags.IsSaved=0; % Flag
                    app.CurrentData.Polygon=[]; 
                    app.CurrentData.Area=[]; 
                    app.CurrentData.Temp=[];
                    app.CylinderButton.Visible='off';
                    app.SegmentationButton.Visible='off';
                    
                end
            end
            
            if strcmp(answer,'Yes') 
                cla(app.UIAxes)
                DisplayRGBimage(app);
                app.EndButton.Visible='on';
                app.CurrentData.Temp = drawrectangle(app.UIAxes,'Label','Artery','Color',[0.2 0 1],'LabelVisible','hover');
            end

        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            % For the green ddata
            if strcmp(app.ThresholdGreenKnob.Visible,'on')
                app.ThresholdGreenKnob.Visible='off';
                app.ThresholdGreenKnobLabel.Visible='off';
                app.EndButton.Visible='off';
                app.NewCellButton.Visible='off';
                app.ThresholdEditField.Visible='off';
                app.ThresholdEditFieldLabel.Visible='off';

                app.CellsStatsButton.Visible='on';
                % Check that all ROIs correspond to non deleted handels
                if length(app.CurrentData.Temp)>2
                    for il=1:length(app.CurrentData.Temp{3})
                        % If the handle is not valid then 
                        if isvalid(app.CurrentData.Temp{3}(il))
                            % Add the ROI
                            try % This is to avoid errors
                                app.CurrentData.ROI{end+1}=app.CurrentData.Temp{3}(il).Position;
                                % make it closed
                                app.CurrentData.ROI{end}=[app.CurrentData.ROI{end};app.CurrentData.ROI{end}(1,:)];
                            end
                        end
                    end
                end
                close(app.CurrentData.Temp{1})
                app.CurrentData.Temp=[];
            end
            % For the artery button the temp field will be not empty
            if ~isempty(app.CurrentData.Temp)
                app.CurrentData.ROIartery=round(app.CurrentData.Temp.Position);
                app.CurrentData.ROIartery(1)=max(1,min(size(app.CurrentData.DataGreenChanel,2),app.CurrentData.ROIartery(1))); % Ensure are between the max abd min
                app.CurrentData.ROIartery(2)=max(1,min(size(app.CurrentData.DataGreenChanel,1),app.CurrentData.ROIartery(2))); % Ensure are between the max abd min
                if app.CurrentData.ROIartery(1)+app.CurrentData.ROIartery(3)>size(app.CurrentData.DataGreenChanel,2) % Make sure the rectangle does not exceed matrix size
                    app.CurrentData.ROIartery(3)=size(app.CurrentData.DataGreenChanel,2)-app.CurrentData.ROIartery(1);
                end
                if app.CurrentData.ROIartery(2)+app.CurrentData.ROIartery(4)>size(app.CurrentData.DataGreenChanel,1) % Make sure the rectangle does not exceed matrix size
                    app.CurrentData.ROIartery(4)=size(app.CurrentData.DataGreenChanel,1)-app.CurrentData.ROIartery(2);
                end
                % Define the dataaround the arteries
                app.CurrentData.DataRedChanel_Artery=app.CurrentData.DataRedChanel(app.CurrentData.ROIartery(2):app.CurrentData.ROIartery(2)+app.CurrentData.ROIartery(4),app.CurrentData.ROIartery(1):app.CurrentData.ROIartery(1)+app.CurrentData.ROIartery(3),:);
                app.CurrentData.DataGreenChanel_Artery=app.CurrentData.DataGreenChanel(app.CurrentData.ROIartery(2):app.CurrentData.ROIartery(2)+app.CurrentData.ROIartery(4),app.CurrentData.ROIartery(1):app.CurrentData.ROIartery(1)+app.CurrentData.ROIartery(3),:);
                % Now replot removing the rectangle
                cla(app.UIAxes)
                DisplayRGBimage(app);
                app.EndButton.Visible='off';
                app.CylinderButton.Visible='on';
                app.CurrentData.Temp=[];

            end
            % For the cylinder the ThresholdRedKnob will be visible
            if strcmp(app.ThresholdRedKnob.Visible,'on')
                app.ThresholdRedKnob.Visible='off';
                app.RadiusKnob.Visible='off';
                app.ThresholdRedKnobLabel.Visible='off';
                app.RadiusKnobLabel.Visible='off';
                app.EndButton.Visible='off';
                app.SegmentationButton.Visible='on';
                app.ThresholdEditField.Visible='off';
                app.ThresholdEditFieldLabel.Visible='off';
                app.RadiusEditField.Visible='off';
                app.RadiusEditFieldLabel.Visible='off';

                app.CurrentData.Flags.IamHappyWithRadious=1;
            end
                
            app.CurrentData.Flags.IsSaved=0;
        end

        % Button pushed function: CylinderButton
        function CylinderButtonPushed(app, event)
            app.ThresholdRedKnob.Visible='on';
            app.RadiusKnob.Visible='on';
            app.RadiusKnobLabel.Visible='on';
            app.ThresholdRedKnobLabel.Visible='on';
            app.EndButton.Visible='on';
            app.ThresholdEditField.Value=app.ThresholdRedKnob.Value;
            app.ThresholdEditField.Visible='on';
            app.ThresholdEditFieldLabel.Visible='on';
            app.RadiusEditField.Value=app.RadiusKnob.Value;
            app.RadiusEditField.Visible='on';
            app.RadiusEditFieldLabel.Visible='on';

            % Read teh 
            app = CalculateCentersAndSegmentation(app);
            cla(app.UIAxes)
            DisplayRGBimage(app)
            
            
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            %% Load the Green image               
            app.ThresholdGreenKnob.Visible='on';
            app.ThresholdGreenKnobLabel.Visible='on';
            app.ThresholdEditField.Value=round(10*app.ThresholdGreenKnob.Value)/10;
            app.ThresholdEditField.Visible='on';            
            app.ThresholdEditFieldLabel.Visible='on';            
            app.EndButton.Visible='on';
            app.NewCellButton.Visible='on';
            app = CalculateProjectionInCylinder(app);
            PlotAlphaAndFinishedROIs(app)
        end

        % Value changing function: RadiusKnob
        function RadiusKnobValueChanging(app, event)
            app.RadiusKnob.Value = event.Value;
            app.RadiusEditField.Value = round(10*app.RadiusKnob.Value)/10;
            cla(app.UIAxes)
            DisplayRGBimage(app);
            
        end

        % Value changed function: ThresholdRedKnob
        function ThresholdRedKnobValueChanged(app, event)
            app.ThresholdEditField.Value=round(10*app.ThresholdRedKnob.Value)/10;
            app=CalculateCentersAndSegmentation(app);
            cla(app.UIAxes)
            DisplayRGBimage(app);            
        end

        % Button pushed function: CellsStatsButton
        function CellsStatsButtonPushed(app, event)
           tic
            % First calculate the concous for the low  threshold
            OldThreshold=app.ThresholdRedKnob.Value;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% JIA IMPORTANT PARAMETER TO CHECK MANUALLY
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.ThresholdRedKnob.Value=4; % 6 for th WT 4 for the mutant
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% END IMPORTANT PARAMETER TO CHECK MANUALLY
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            app=CalculateCentersAndSegmentation(app);
            SpolyB=app.CurrentData.Polygon;
            app.ThresholdRedKnob.Value=OldThreshold;
            app=CalculateCentersAndSegmentation(app);
            Spoly=app.CurrentData.Polygon;
            clear OldThreshold
            
            %if ~isfield(app.CurrentData,'Resolution')
            %    app.CurrentData.Resolution=[1 1 1];
            %end
            
            %ShouldIAskForResolution=0;
            %if isempty(app.CurrentData.Resolution)
            %    ShouldIAskForResolution=1;
            %elseif sum(abs(app.CurrentData.Resolution-[1 1 1]))==0
            %    ShouldIAskForResolution=1;
            %end
            if 1%ShouldIAskForResolution
                answer = inputdlg({'Resolution X (\mu m)','Resolution Y (\mu m)','Resolution Z (\mu m)'},'Enter Resolution',[1 35],{'.2841','.2841','.4249'});
                app.CurrentData.Resolution=str2double(answer);
                app.CurrentData.Resolution=app.CurrentData.Resolution([1 3 2]); % This is to be consistent with the permutation of hte dataset
            end
            Rez = app.CurrentData.Resolution;
            
            
            % Define the excel and header
            ExcelFile=fullfile(app.CurrentData.Directory, [app.CurrentData.FileSaved(1:end-4) app.Version '.xlsx']);
            Header={'Cell ID','Axial Length straight (um)','Transveral length (um)','Angle with artery (deg)','Cell area','Cell Volume','Mean Inertia (um^2)','Anisotropy Inertia','I1 (um2)','I2 (um2)','I3 (um2)','Axial length inner aorta (um)','Perimeter (um)','Elongation'};
            countE=1;
            writecell(Header, ExcelFile, 'Sheet', 1, 'Range', ['A' num2str(countE)]);  

            %% Desing final code for all slices simultaneously
                 
            nSlices=length(Spoly);
            
            Profile=cell(nSlices,2); % For each slice the firt is the outer diameter 
                                           % the second hte inner diameter
            AortaWalls=cell(nSlices,2);
            for i_sl=1:nSlices
                % We start by identifying if there are obejects that overlapp and that
                % can be the outter and inner aorta walls
                N=length(Spoly{i_sl});
                Mat=size(app.CurrentData.DataGreenChanel_Artery);
                Mat=Mat(1:2);
                [c,Largest]=CalculateOverlappBetweenPolygones(Spoly{i_sl},Mat);
                AortaWalls{i_sl,1}=Spoly{i_sl}{Largest};
                if max(c(:))>0.10 % If htere is more than 30% overlap then one is 
                    % the exterior wall and the other the interior
                    % Save the interior Aorta wall. This will be used later to expand
                    % the lumenof SpolyB
                    [X,Y]=find(c==max(c(:)));
                    if X==Largest
                        AortaWalls{i_sl,2}=Spoly{i_sl}{Y};
                    else
                        AortaWalls{i_sl,2}=Spoly{i_sl}{X};
                    end
                end
                % If there is no overlapp take the largest piece
                % In such case we have not yet identified the correct profile and
                % will requrie to use the SpolyB profiles drawn at a lower
                % threshold
                [cB,LargestB]=CalculateOverlappBetweenPolygones(SpolyB{i_sl},Mat);
                [X,Y]=find(cB==max(cB(:)));
                if ~isempty(Spoly{i_sl})
                    c=CalculateOverlappBetweenPolygones({SpolyB{i_sl}{X},Spoly{i_sl}{Largest}},Mat);
                    cO(1)=max(c(:));
                    c=CalculateOverlappBetweenPolygones({SpolyB{i_sl}{Y},Spoly{i_sl}{Largest}},Mat);
                    cO(2)=max(c(:));
                else
                    disp(['Slice' blanks(1) num2str(i_sl) blanks(1) 'Spoly empty']) 
                    cO=[0 0];
                end
                % If cB has overlapp then Bingo!
                if max(cB(:))>0.0
                    if X==LargestB
                        Profile{i_sl,1}=SpolyB{i_sl}{X};
                        Profile{i_sl,2}=SpolyB{i_sl}{Y};
                    else        
                        Profile{i_sl,1}=SpolyB{i_sl}{Y};
                        Profile{i_sl,2}=SpolyB{i_sl}{X};
                    end  
                    %disp(['Slice' blanks(1) num2str(i_sl) blanks(1) 'SUCCESFUL identify contours:' ...
                    %    blanks(1) 'c=' num2str(max(cB(:))) ', cO1=' num2str(cO(1)) ',cO2=' num2str(cO(2)) '.' ]) 
                else
            
                    disp(['Slice' blanks(1) num2str(i_sl) blanks(1) 'Failure to identify contours:' ...
                        blanks(1) 'c=0, cO1=' num2str(cO(1)) ',cO2=' num2str(cO(2)) '.' ]) 
                end
                
            end
            
            %% Part o the code to stretch the lumen identified with the low treshold and increase to theinitially selected threshold
            
            count=[];
            for i_i=1:nSlices%[1:222 224:274 277:495 499:551 553:575 577:650 652:714 716:836 838:856 858:995 998:nSlices]
                try
                    Center=mean(Profile{i_i,2}(:,[1 2]));
                    if isempty(AortaWalls{i_i,2}) 
                        % In this case we have an openned countour and need to identify the
                        % gap on the walls, This is ma
                        InnerAortaB=ConvertToPolarCoordinates(Profile{i_i,2}(:,1:2),Center);
                        OuterAortaB=ConvertToPolarCoordinates(Profile{i_i,1}(:,1:2),Center);
                        Aorta=ConvertToPolarCoordinates(AortaWalls{i_i,1}(:,1:2),Center);
            
                        %Lets identify the gap which when exist is the largest gap in angle
                        %between 0 and 2 pi, this allows us to identify the extremes
                        [Aux,Order]=sort(Aorta(:,2));
                        I=find(Aux(1:end-1)-Aux(2:end)==min(Aux(1:end-1)-Aux(2:end)));
                        % Calculae the gap of forbiden values and extend it by 5 degrees
                        Gap=[Aux(I) Aux(I+1)]; % Remeer becai=ause of sorting Gap(1) < Gap(2)
                        Gap(1)=Gap(1)-20/180*pi;
                        Gap(2)=Gap(2)+20/180*pi;
            
                        I=sort(Order([I, I+1])); % Identify the two extremes
                        % Then identify the outer and inner edges
                        if mean(Aorta(I(1)+1:I(2)-1,1))>mean(Aorta([1:I(1)-1 I(2)+1:end],1))
                            OuterAorta=unique(Aorta(I(1):I(2),:),'rows'); % unique is done preventvely since we are working with polynoms that have 1 repeated point
                            InnerAorta=unique(Aorta([1:I(1) I(2):end],:),'rows');
                        else
                            InnerAorta=unique(Aorta(I(1):I(2),:),'rows');
                            OuterAorta=unique(Aorta([1:I(1) I(2):end],:),'rows');
                        end
            
                        %% First for the inner diameter
                        % Lets find the two DR at both sides of the gap
                        AuxB=unique(InnerAortaB(:,2),'rows','stable');
                        try
                            I1=find(AuxB==max(AuxB(AuxB<Gap(1)))); % Lower extreem
                        catch
                            % if this fails is because there is no value beloow because the
                            % value is close to zero. So II=end (~2pi) and II+1=1;
                            %Solution shift the InnerAortaB 
                            InnerAortaB=unique(InnerAortaB,'rows','stable');
                            InnerAortaB=InnerAortaB([end,1:end-1],:);
                            InnerAortaB(1,2)=InnerAortaB(1,2)-2*pi;
                            AuxB=unique(InnerAortaB(:,2),'rows','stable');
                            I1=find(AuxB==max(AuxB(AuxB<Gap(1)))); % Lower extreem
                        end
                        I2=find(AuxB==min(AuxB(AuxB>Gap(2)))); % Upper extreme
                        I3=find(AuxB>=Gap(1)&AuxB<=Gap(2)); % Al values to interpolate
            
                        Aux=InnerAorta(:,2)-AuxB(I1);
                        % The projection of the in hte two lowest points
                        II=find(Aux==max(Aux(Aux<0)));
                        DR1=InnerAorta(II,1)+(InnerAorta(II+1,1)-InnerAorta(II,1))*(AuxB(I1)-InnerAorta(II,2))/(InnerAorta(II+1,2)-InnerAorta(II,2))-InnerAortaB(I1,1);                
            
                        Aux=InnerAorta(:,2)-AuxB(I2);
                        % The projection of the in hte two lowest points
                        II=find(Aux==min(Aux(Aux>0)));
                        DR2=InnerAorta(II,1)+(InnerAorta(II+1,1)-InnerAorta(II,1))*(AuxB(I2)-InnerAorta(II,2))/(InnerAorta(II+1,2)-InnerAorta(II,2))-InnerAortaB(I2,1);                
            
                        % For all other points we can use the interplation between DRI at
                        % Gap(1) adn DR2 at Gap(2)
                        InnerAortaB(I3,1)=InnerAortaB(I3,1)+DR1+(DR2-DR1)*(InnerAortaB(I3,2)-InnerAortaB(I1,2))/(InnerAortaB(I2,2)-InnerAortaB(I1,2));
            
                        % So the final AortaWalls{i_i,2} select all points outside the
                        % gap. They add the expanded points of the InnerAoortaB 
                        Aux=InnerAorta(InnerAorta(:,2)<=Gap(1)|InnerAorta(:,2)>=Gap(2),:);
                        Aux=[Aux;InnerAortaB(I3,:)];
                        [Ignore,Order]=sort(Aux(:,2));
                        Aux=Aux(Order,:);
                        Aux=[Aux(:,1).*cos(Aux(:,2)),Aux(:,1).*sin(Aux(:,2)),Profile{i_i,2}(1,3)*ones(size(Aux,1),1)];
                        Aux=[Aux;Aux(1,:)];
                        Aux(:,1)=Aux(:,1)+Center(1);
                        Aux(:,2)=Aux(:,2)+Center(2);
                        % Finalize by saving the inner
                        AortaWalls{i_i,2}=Aux;
            
                        %% Second for the Outer diameter
            
                        % Lets find the two DR at both sides of the gap
                        AuxB=unique(OuterAortaB(:,2),'row','stable');
                        I1=find(AuxB==max(AuxB(AuxB<Gap(1)))); % Lower extreem
                        I2=find(AuxB==min(AuxB(AuxB>Gap(2)))); % Upper extreme
                        I3=find(AuxB>=Gap(1)&AuxB<=Gap(2)); % Al values to interpolate
            
                        Aux=OuterAorta(:,2)-AuxB(I1);
                        % The projection of the in hte two lowest points
                        II=find(Aux==max(Aux(Aux<0)));
                        DR1=OuterAorta(II,1)+(OuterAorta(II+1,1)-OuterAorta(II,1))*(AuxB(I1)-OuterAorta(II,2))/(OuterAorta(II+1,2)-OuterAorta(II,2))-OuterAortaB(I1,1);                
            
                        Aux=OuterAorta(:,2)-AuxB(I2);
                        % The projection of the in hte two lowest points
                        II=find(Aux==min(Aux(Aux>0)));
                        DR2=OuterAorta(II,1)+(OuterAorta(II+1,1)-OuterAorta(II,1))*(AuxB(I2)-OuterAorta(II,2))/(OuterAorta(II+1,2)-OuterAorta(II,2))-OuterAortaB(I2,1);                
            
                        % For all other points we can use the interplation between DRI at
                        % Gap(1) adn DR2 at Gap(2)
                        OuterAortaB(I3,1)=OuterAortaB(I3,1)+DR1+(DR2-DR1)*(OuterAortaB(I3,2)-OuterAortaB(I1,2))/(OuterAortaB(I2,2)-OuterAortaB(I1,2));
            
                        % So the final AortaWalls{i_i,2} select all points outside the
                        % gap. They add the expanded points of the InnerAoortaB 
                        Aux=OuterAorta(OuterAorta(:,2)<=Gap(1)|OuterAorta(:,2)>=Gap(2),:);
                        Aux=[Aux;OuterAortaB(I3,:)];
                        [Ignore,Order]=sort(Aux(:,2));
                        Aux=Aux(Order,:);
                        Aux=[Aux(:,1).*cos(Aux(:,2)),Aux(:,1).*sin(Aux(:,2)),Profile{i_i,2}(1,3)*ones(size(Aux,1),1)];
                        Aux=[Aux;Aux(1,:)];
                        Aux(:,1)=Aux(:,1)+Center(1);
                        Aux(:,2)=Aux(:,2)+Center(2);
            
                        % Finalize by saving the inner
                        AortaWalls{i_i,1}=Aux;        
            
                    end
                catch
                    count=[count;i_i];
                end
            end
            
            
            %% Find a diagonal reference sysem 
            %  and interpolate all the poins in the area to fixed angles
            CenterF=zeros(nSlices,3);
            for i_i=1:nSlices
                % For the onse we have manage to find a closed inner lumen
                if ~isempty(AortaWalls{i_i,2})
                     CenterF(i_i,:)=[mean(AortaWalls{i_i,2}(:,1)),mean(AortaWalls{i_i,2}(:,2)),mean(AortaWalls{i_i,2}(:,3))]; % Bo Shen change from Outer Walls to Inner Walls      
                end    
            end
            % Calculate the best line fitting the center. This still need
            % ot be checked for consistency
            px=polyfit(CenterF(CenterF(:,1)>0,3),CenterF(CenterF(:,1)>0,1),1);
            py=polyfit(CenterF(CenterF(:,1)>0,3),CenterF(CenterF(:,1)>0,2),1);
            Comp=[px(1)*Rez(1),;py(1)*Rez(2);1*Rez(3)];
            Comp=Comp/norm(Comp);
            
            % Now calculate the line of the SR Mking sure that the center is shifted by
            % 1 unit in the Z-direction
            CenterSR=[polyval(px,1:nSlices)',polyval(py,1:nSlices)',(1:nSlices)'];
            %CenterSR=[CenterF(1,1)+Comp(1,1)/Comp(3,1)*(0:1023)',CenterF(1,2)+Comp(2,1)/Comp(3,1)*(0:1023)',CenterF(1,3)+(0:1023)'];
            
            %DeltaCenter=[Comp(1,1)/Comp(3,1) Comp(2,1)/Comp(3,1) 1];


            %% Smooth priofile and represent 
            clear Polar*
            AngleI=[(0:5/180*pi:2*pi)'; 0];
            nAngle=length(AngleI);
            Polar=zeros(length(AngleI),nSlices);
            Polarum=zeros(length(AngleI),nSlices);
            CenterPolar=zeros(nSlices,3);
            for i_i=1:nSlices
                if CenterF(i_i,1)>0
                    Aux=ConvertToPolarCoordinates(AortaWalls{i_i,2}(:,1:2),CenterSR(i_i,1:2),AngleI');
                    Polar(:,i_i)=Aux(:,1);
                    Aux=ConvertToPolarCoordinates(AortaWalls{i_i,2}(:,1:2).*Rez(1:2)',CenterSR(i_i,1:2).*Rez(1:2)',AngleI');
                    Polarum(:,i_i)=Aux(1);
                    Aux=ConvertToPolarCoordinates(AortaWalls{i_i,1}(:,1:2),CenterSR(i_i,1:2),AngleI');
                    PolarOut(:,i_i)=Aux(:,1);
                end
            end
            
            % Cubic sline for vertical
            Polarp=zeros(nAngle,nSlices);
            for i=1:nAngle
                Polarp(i,:)=csaps(CenterSR(Polar(1,:)>0,3),Polar(i,Polar(1,:)>0),0.5,(1:nSlices)');
            end
            [Xs,Ys]=pol2cart(repmat(AngleI ,[1, nSlices]),Polarp);
            
            % Smooth
            [PolarT,p]   =csaps({[AngleI-2*pi;AngleI;AngleI+2*pi],CenterSR(Polar(1,:)>0,3)},repmat(Polar(:,Polar(1,:)>0),[3,1]),0.99,{AngleI,1:nSlices});
            [PolarOutT,p]=csaps({[AngleI-2*pi;AngleI;AngleI+2*pi],CenterSR(Polar(1,:)>0,3)},repmat(PolarOut(:,PolarOut(1,:)>0),[3,1]),0.99,{AngleI,1:nSlices});
            [PolarTum,~]   =csaps({[AngleI-2*pi;AngleI;AngleI+2*pi],CenterSR(Polarum(1,:)>0,3)},repmat(Polarum(:,Polarum(1,:)>0),[3,1]),0.99,{AngleI,(1:nSlices)*Rez(3)});
            [XT,YT]=pol2cart(repmat(AngleI ,[1, nSlices]),PolarT);
            [XTout,YTout]=pol2cart(repmat(AngleI ,[1, nSlices]),PolarOutT);
            
            % Smooth and interpolate the missing slices 
            [Polar,p]=csaps({AngleI,CenterSR(Polar(1,:)>0,3)},Polar(:,Polar(1,:)>0),0.8,{AngleI,1:nSlices});
            [XS,YS]=pol2cart(repmat(AngleI ,[1, nSlices]),Polar);
            
            % These variables are for plot keep them this way do not remove
            Zo=repmat(1:nSlices,[74,1]);
            CenterSR_Xo=repmat(CenterSR(:,1)',[nAngle 1]);
            CenterSR_Yo=repmat(CenterSR(:,2)',[nAngle 1]);            
            
            % Smooth the CenterF valriable and fill in the misisng slides
            % Extrapolte the centers of the oarta walls for the reconstruction
            CenterF(:,1)=csaps(CenterF(CenterF(:,1)>0,3),CenterF(CenterF(:,1)>0,1),0.5,(1:nSlices)');
            CenterF(:,2)=csaps(CenterF(CenterF(:,1)>0,3),CenterF(CenterF(:,1)>0,2),0.5,(1:nSlices)');
            CenterF(:,3)=1:nSlices;
            
            %% Back project the ROI based on the centers
             
            %% ROI
            %% ROI
            %% ROI
            
            % First find the ROI for each point
            CenterSRorig=CenterSR;
            CenterForig=CenterF;
            ProjectedCellAll=cell(length(app.CurrentData.ROI),2);
            for ij=1:length(app.CurrentData.ROI)
                %% Identify the ROI and identify the points so you have contours for
                % each slice and we can back project by lines in the 3D aorta
                CellContour=[];
                CenterSR_X=[];
                CenterSR_Y=[];
                clear Xp
                CenterSR=CenterSRorig;
                CenterF=CenterForig;
                ROI1c=app.CurrentData.ROI{ij};
                ROI1c(:,2)=min(nSlices, max(1,round(ROI1c(:,2)))); % Max 1 and min nSlices to avoid the case that the segmentation exceed the lenght of the slices
                for i_side=1:size(ROI1c,1)-1
                    % First interpolate for all z
                    if ROI1c(i_side,2)>ROI1c(i_side+1,2)
                        CellContour=[CellContour;ROI1c(i_side,:);...
                            [ROI1c(i_side,1)+(ROI1c(i_side+1,1)-ROI1c(i_side,1))*...
                            (((ROI1c(i_side,2)-1):-1:(ROI1c(i_side+1,2)+1))'-ROI1c(i_side,2))/(ROI1c(i_side+1,2)-ROI1c(i_side,2))...
                            ,((ROI1c(i_side,2)-1):-1:(ROI1c(i_side+1,2)+1))'];...
                            ROI1c(i_side+1,:)];
                    elseif ROI1c(i_side,2)<ROI1c(i_side+1,2)
                        CellContour=[CellContour;ROI1c(i_side,:);...
                            [ROI1c(i_side,1)+(ROI1c(i_side+1,1)-ROI1c(i_side,1))*...
                            (((ROI1c(i_side,2)+1):(ROI1c(i_side+1,2)-1))'-ROI1c(i_side,2))/(ROI1c(i_side+1,2)-ROI1c(i_side,2))...
                            ,((ROI1c(i_side,2)+1):(ROI1c(i_side+1,2)-1))'];...
                            ROI1c(i_side+1,:)];
                    else
                        CellContour=[CellContour;ROI1c(i_side:i_side+1,:)];
                    end
                end
                % Now Check for angles bellow alpha step degrees
                I=find(abs(CellContour(1:end-1,1)-CellContour(2:end,1))>5/180*pi);
                Step=2/180*pi;
                for i=length(I):-1:1 % For each position wiht more than 5 degress undersample
                    Aux=[];
                    if CellContour(I(i),1)>CellContour(I(i)+1,1)
                        x=(CellContour(I(i),1)-Step:-Step:CellContour(I(i)+1,1))';
                        Aux=[x,CellContour(I(i),2) - (CellContour(I(i),2) - CellContour(I(i)+1,2))*...
                            (x-CellContour(I(i),1))/(CellContour(I(i)+1,1)-CellContour(I(i),1))]; 
                    elseif CellContour(I(i),1)<CellContour(I(i)+1,1)
                        x=(CellContour(I(i),1)+Step:Step:CellContour(I(i)+1,1))';
                        Aux=[x,CellContour(I(i),2) - (CellContour(I(i),2) - CellContour(I(i)+1,2))*...
                            (x-CellContour(I(i),1))/(CellContour(I(i)+1,1)-CellContour(I(i),1))]; 
                    elseif CellContour(I(i),1)==CellContour(I(i)+1,1)
                        disp('Error SSSS')
                    end
                    CellContour=[CellContour(1:I(i),:);Aux;CellContour(I(i)+1:end,:)];
                end
                CellContour(:,1)=csaps(1:size(CellContour,1),CellContour(:,1),0.001,(1:size(CellContour,1))');
                CellContour(end+1,:)=CellContour(1,:); % make it polygone
                
                %% Now exend the slices to include all that are not integer Z
                % This requies two actions: 1) identify all crosing of the polygon with the
                % not integet z lines; 2) Add the slice of the non-integer z to the Aorta
                % walls
                % NOte: It is extremely mportant to create the expansion without breaking
                % the order of the points
                I=find(round(CellContour(:,2))~=CellContour(:,2)); % identify the non integer z
                [ignore,IU,IC]=unique(CellContour(I,2));
                I=CellContour(I(IU),2);
            
                % Now identify all side that intercept with the line and not at an endpoint
                %CellContour=CC; % Just testing rto be removed
                for iz=1:length(I)
                    SidesZ=[CellContour(1:end-1,2),CellContour(2:end,2)];
                    J=find(sign(SidesZ(:,1)-I(iz))+sign(SidesZ(:,2)-I(iz))==0);
                    if isempty(J)
                        disp('oppsss')
                    end
                    for i_j=length(J):-1:1
                        Aux=CellContour(J(i_j),:)+(CellContour(J(i_j)+1,:)-CellContour(J(i_j),:)).*(I(iz)-CellContour(J(i_j),2))./(CellContour(J(i_j)+1,2)-CellContour(J(i_j),2));
                        CellContour=[CellContour(1:J(i_j),:);Aux;CellContour(J(i_j)+1:end,:)];            
                    end
                end
                
                %% Now extend the Aorta Wall for all slices
                I=find(round(CellContour(:,2))~=CellContour(:,2)); % identify the non integer z
                [ignore,IU,IC]=unique(CellContour(I,2));
                I=CellContour(I(IU),2);
                Polar=PolarT;
                PolarOut=PolarOutT;
                for iz=length(I):-1:1
                    % We need to interpolate in z between the 
                    Polar=[Polar(:,1:floor(I(iz))),...
                           Polar(:,floor(I(iz)))+(Polar(:,ceil(I(iz)))-Polar(:,floor(I(iz))))*(I(iz)-floor(I(iz))),...
                           Polar(:,ceil(I(iz)):end)];
                    PolarOut=[PolarOut(:,1:floor(I(iz))),...
                           PolarOut(:,floor(I(iz)))+(PolarOut(:,ceil(I(iz)))-PolarOut(:,floor(I(iz))))*(I(iz)-floor(I(iz))),...
                           PolarOut(:,ceil(I(iz)):end)];
                    CenterSR=[CenterSR(1:floor(I(iz)),:);...
                           [CenterSR(floor(I(iz)),1:2)+(CenterSR(ceil(I(iz)),1:2)-CenterSR(floor(I(iz)),1:2))*(I(iz)-floor(I(iz))) I(iz)];...
                           CenterSR(ceil(I(iz)):end,:)];           
                    CenterF=[CenterF(1:floor(I(iz)),:);...
                           [CenterF(floor(I(iz)),1:2)+(CenterF(ceil(I(iz)),1:2)-CenterF(floor(I(iz)),1:2))*(I(iz)-floor(I(iz))) I(iz)];...
                           CenterF(ceil(I(iz)):end,:)];           
                end
                  
                
                %% Finaly project back to the surface we have the wall based on CenterSR
                % and the projections based on the CenterF. So we need to convert the
                % refeence and find the interaction between the angles
                
                % Try one way which i sconvert to cartesina and back to polar
                [X,Y]=pol2cart(repmat(AngleI ,[1, size(Polar,2)]),Polar);
                [Xout,Yout]=pol2cart(repmat(AngleI ,[1, size(PolarOut,2)]),PolarOut);
                
                %Z=repmat(CenterSR(:,3)',[74,1]);
                CenterSR_X=repmat(CenterSR(:,1)',[nAngle 1]);
                CenterSR_Y=repmat(CenterSR(:,2)',[nAngle 1]);
                CenterF_X=repmat(CenterF(:,1)',[nAngle 1]);
                CenterF_Y=repmat(CenterF(:,2)',[nAngle 1]);
                try
                Xp=X+CenterSR_X-CenterF_X;
                catch
                    disp('Bug2')
                end
                Yp=Y+CenterSR_Y-CenterF_Y;
                [TH,R]=cart2pol(Xp,Yp);
                Xp=Xout+CenterSR_X-CenterF_X;
                Yp=Yout+CenterSR_Y-CenterF_Y;
                [THout,Rout]=cart2pol(Xp,Yp);
                
                % Now interpolate to identify projection
                % Convert all the angles between -pi and pi
                CellContourI=CellContour;
                CellContourI(:,1)=angle(exp(sqrt(-1)*CellContourI(:,1))); % Convert alpha to -pi to pi
                
                ProjectedContour=[];
                ProjectedContourOut=[];
                for iz=1:size(CellContourI,1)
                    % For each point find the intersection
                    I=find(CenterF(:,3)==CellContourI(iz,2));
                    % I is the slice number Calculate the R
                    J=find(TH(:,I)-CellContourI(iz,1)>0);
                    Jout=find(THout(:,I)-CellContourI(iz,1)>0);
                    if isempty(J)
                        % If J is empty means that there is no TH(:,I) value larger
                        % than CellContourI(iz,1) and then we have to find
                        % the closes of all maximum
                        J=find(TH(:,I)-CellContourI(iz,1)==min(TH(:,I)-CellContourI(iz,1)),1,'first');
                    else
                        % Otherwide from all values of TH lrger than
                        % CellContour(iz,1) find the minimum (i.e., the
                        % closest from the top to the value
                        J=find(TH(:,I)-CellContourI(iz,1)==min(TH(J,I)-CellContourI(iz,1)),1,'first');
                    end
                    if isempty(Jout)
                        Jout=find(THout(:,I)-CellContourI(iz,1)==min(THout(:,I)-CellContourI(iz,1)),1,'first');
                    else
                        Jout=find(THout(:,I)-CellContourI(iz,1)==min(THout(Jout,I)-CellContourI(iz,1)),1,'first');
                    end
                    % We repeat the contour from the lower values to
                    % idnetify the to closest points to interpolate
                    K=find(TH(:,I)-CellContourI(iz,1)<=0);
                    if isempty(K)
                        % The same argument here if there is no K value
                        % then you have to find the maximum value
                        K=find(TH(:,I)-CellContourI(iz,1)==max(TH(:,I)-CellContourI(iz,1)),1,'last');
                    else
                        % 
                        K=find(TH(:,I)-CellContourI(iz,1)==max(TH(K,I)-CellContourI(iz,1)),1,'last');
                    end
                    Kout=find(THout(:,I)-CellContourI(iz,1)<=0);
                    if isempty(Kout)
                        Kout=find(THout(:,I)-CellContourI(iz,1)==max(THout(:,I)-CellContourI(iz,1)),1,'last');
                    else
                        Kout=find(THout(:,I)-CellContourI(iz,1)==max(THout(Kout,I)-CellContourI(iz,1)),1,'last'); % Check
                    end
                    if abs(TH(J,I)-TH(K,I))<pi % You are not close to a jump from -pi to pi
                        if TH(K,I)==TH(J,I)
                            Rc=R(J,I);
                        else
                            Rc=R(J,I)+(R(K,I)-R(J,I))*(CellContourI(iz,1)-TH(J,I))/(TH(K,I)-TH(J,I));
                        end
                    elseif sign( CellContourI(iz,1))==sign(TH(J,I)) % Then add the 2*pi to the K
                        Al=TH(K,I)+sign(TH(J,I))*2*pi;
                        Rc=R(J,I)+(R(K,I)-R(J,I))*(CellContourI(iz,1)-TH(J,I))/(Al-TH(J,I));            
                    else
                        Al=TH(J,I)+sign(TH(K,I))*2*pi;
                        Rc=R(J,I)+(R(K,I)-R(J,I))*(CellContourI(iz,1)-TH(J,I))/(Al-TH(J,I));
                    end
                    if isnan(Rc)
                        disp('NAAAAN')
                    end
                    if abs(THout(Jout,I)-THout(Kout,I))<pi % You are not close to a jump from -pi to pi
                        if THout(Kout,I)==THout(Jout,I)
                            Rcout=Rout(Jout,I);
                        else
                            Rcout=Rout(Jout,I)+(Rout(Kout,I)-Rout(Jout,I))*(CellContourI(iz,1)-THout(Jout,I))/(THout(Kout,I)-THout(Jout,I));
                        end
                    elseif sign( CellContourI(iz,1))==sign(THout(Jout,I)) % Then add the 2*pi to the K
                        Alout=THout(Kout,I)+sign(THout(Jout,I))*2*pi;
                        Rcout=Rout(Jout,I)+(Rout(Kout,I)-Rout(Jout,I))*(CellContourI(iz,1)-THout(Jout,I))/(Alout-THout(Jout,I));            
                    else
                        Alout=THout(Jout,I)+sign(THout(Kout,I))*2*pi;
                        Rcout=Rout(Jout,I)+(Rout(Kout,I)-Rout(Jout,I))*(CellContourI(iz,1)-THout(Jout,I))/(Alout-THout(Jout,I));
                    end
                    try
                    ProjectedContour=[ProjectedContour;...
                        [Rc*cos(CellContourI(iz,1))+CenterF(I,1)-CenterSR(I,1)...
                         Rc*sin(CellContourI(iz,1))+CenterF(I,2)-CenterSR(I,2)...  
                         CellContourI(iz,2) CenterSR(I,1) CenterSR(I,2) ]];
                    catch
                        disp('Uppps')
                    end
                    ProjectedContourOut=[ProjectedContourOut;...
                        [Rcout*cos(CellContourI(iz,1))+CenterF(I,1)-CenterSR(I,1)...
                         Rcout*sin(CellContourI(iz,1))+CenterF(I,2)-CenterSR(I,2)...  
                         CellContourI(iz,2) CenterSR(I,1) CenterSR(I,2)]];
                    if Rcout>max(Rout(:,I))  
                        disp('Te pille')
                    end
                end  
                ProjectedCellAll{ij,1}=ProjectedContour;
                ProjectedCellAll{ij,2}=ProjectedContourOut;
                
                %% Finally count the area identify all points in polygon inner and outer wall
                % Start by projecting the cell in hte polar system of the aorta so we
                % can define the points that are in the polygon
                [A,B]=cart2pol(ProjectedContour(:,1),ProjectedContour(:,2));
                Aunwrapped=UnwrappPolar(A);
                PPC=[B,Aunwrapped,ProjectedContour(:,3)];
                [ignore,B]=cart2pol(ProjectedContourOut(:,1),ProjectedContourOut(:,2));
                PPCout=[B,Aunwrapped,ProjectedContourOut(:,3)]; % Use the same angels as both should be the saem see few lines before
                % Check if the angle exceeds the 0 to 2pi range and add copy of points
                if min(PPC(:,2))<0
                    %Then add a copy of polar below 
                    AngleIi=[AngleI(1:end-1,1)-2*pi; AngleI(1:end-1,1)];
                    PolarI=repmat(Polar(1:end-1,:),[2,1]);
                    PolarOutI=repmat(PolarOut(1:end-1,:),[2,1]);
                else
                    AngleIi=AngleI(1:end-1,1);
                    PolarI=Polar(1:end-1,:);
                    PolarOutI=PolarOut(1:end-1,:);
                end
                if max(PPC(:,2))>2*pi
                    %Then add a copy of polar below 
                    AngleIi=[AngleIi; AngleI(1:end-1,1)+2*pi];
                    PolarI=[PolarI;Polar(1:end-1,:)];
                    PolarOutI=[PolarOutI;PolarOut(1:end-1,:)];
                end
                % Now identify all points in the coiontour that are in the polygon
                [X,Y]=meshgrid(AngleIi,CenterSR(:,3)); % Be carefull that X and Y have fliped slice heights
                I=inpolygon(X(:),Y(:),PPC(:,2),PPC(:,3));
                I=reshape(I,size(X));
               
                % Go line by line for the inner aorta
                Yunique=unique(Y(I));
                Area=[];
                AreaOut=[];
                Volume=[];
                Section={};
                Sectionin={};
                LinieAll=[];
                LinieAllOut=[];
                hold on
                for i=1:length(Yunique)
                    % Identify the lside
                    Slice=find(Y(:,1)==Yunique(i));
                    % Identify the points in the slide
                    K=find(I(Slice,:)==1);
                    %Neighbor distance
                    Dst=abs(K(1:end-1)-K(2:end));
                    Segments=[1,find(Dst>1)+1,length(K)+1]; % Distance larger than one unit is indicative of a gap -> New segmnts
                    r=PPC(PPC(:,3)==Yunique(i),1);
                    rout=PPCout(PPCout(:,3)==Yunique(i),1);
                    theta=PPC(PPC(:,3)==Yunique(i),2); % Theta is the same for both projections
                    for i_s=1:length(Segments)-1 % For each segments
                        % Identify the countoout points closing each segment
                        A=find(theta<AngleIi(K(Segments(i_s))),1,'first');
                        Itheta=A(theta(A)-AngleIi(K(Segments(i_s)))==max(theta(A)-AngleIi(K(Segments(i_s)))));
                        if isempty(Itheta)
                            Itheta=find(abs(theta-AngleIi(K(Segments(i_s))))==min(abs(theta-AngleIi(K(Segments(i_s)))))); % If emapty take the closest one
                        end
                        A=find(theta>AngleIi(K(Segments(i_s+1)-1)),1,'first');
                        Jtheta=find(theta(A)-AngleIi(K(Segments(i_s+1)-1))==min(theta(A)-AngleIi(K(Segments(i_s+1)-1))));
                        if isempty(Jtheta)
                            Jtheta=find(abs(theta-AngleIi(K(Segments(i_s))))==min(abs(theta-AngleIi(K(Segments(i_s)))))); % If emapty take the closest one
                        end
                        %Calculate all the points
                        Line=[r(Itheta)*cos(theta(Itheta)),r(Itheta)*sin(theta(Itheta));...
                            PolarI(K(Segments(i_s):Segments(i_s+1)-1),CenterSR(:,3)==Yunique(i)).*cos(AngleIi(K(Segments(i_s):Segments(i_s+1)-1))),PolarI(K(Segments(i_s):Segments(i_s+1)-1),CenterSR(:,3)==Yunique(i)).*sin(AngleIi(K(Segments(i_s):Segments(i_s+1)-1)));...
                            r(Jtheta)*cos(theta(Jtheta)),r(Jtheta)*sin(theta(Jtheta))];
                        Line=unique(Line,'rows','stable');
                        
                        LineOut=[rout(Itheta)*cos(theta(Itheta)),rout(Itheta)*sin(theta(Itheta));...
                            PolarOutI(K(Segments(i_s):Segments(i_s+1)-1),CenterSR(:,3)==Yunique(i)).*cos(AngleIi(K(Segments(i_s):Segments(i_s+1)-1))),PolarOutI(K(Segments(i_s):Segments(i_s+1)-1),CenterSR(:,3)==Yunique(i)).*sin(AngleIi(K(Segments(i_s):Segments(i_s+1)-1)));...
                            rout(Jtheta)*cos(theta(Jtheta)),rout(Jtheta)*sin(theta(Jtheta))];
                        LineOut=unique(LineOut,'rows','stable');
                        LinieAll=[LinieAll;[Line Yunique(i)*ones(size(Line,1),1)]];
                        LinieAllOut=[LinieAllOut;[LineOut Yunique(i)*ones(size(LineOut,1),1)]];
                        Aux=ones(size(Line,1)+1+size(LineOut,1),1);
                        Section{end+1}=[[Line;flipud(LineOut);Line(1,:)],Yunique(i)*Aux, Aux*CenterSR(CenterSR(:,3)==Yunique(i),1:2)];
                        Sectionin{end+1}=[Line, Yunique(i)*ones(size(Line,1),1), ones(size(Line,1),1)*CenterSR(CenterSR(:,3)==Yunique(i),1:2)];
                        % Calculate the area    
                        Line(:,1)=Line(:,1)*app.CurrentData.Resolution(1);
                        Line(:,2)=Line(:,2)*app.CurrentData.Resolution(2);
                        Line=sqrt(sum((Line(1:end-1,:)-Line(2:end,:)).^2,2)); % summed distances between pairs of points on the same line
                        if i<length(Yunique)
                            Area=[Area,sum(Line(Line<5))*(Yunique(i+1)-Yunique(i))*app.CurrentData.Resolution(3)];
                            Volume=[Volume,polyarea(Section{end}(:,1)*app.CurrentData.Resolution(1),Section{end}(:,2)*app.CurrentData.Resolution(2))*(Yunique(i+1)-Yunique(i))*app.CurrentData.Resolution(3)];
                        else
                            Area=[Area,sum(Line(Line<5))*(floor(Yunique(i))+1-Yunique(i))*app.CurrentData.Resolution(3)]; % For the last use the rest to 1 unit
                            Volume=[Volume,polyarea(Section{end}(:,1)*app.CurrentData.Resolution(1),Section{end}(:,2)*app.CurrentData.Resolution(2))*(floor(Yunique(i))+1-Yunique(i))*app.CurrentData.Resolution(3)];
                        end
                    end
                   
                end
                
                %% Now is time to calcuate and plot!
                figure,
                surf((XT+CenterSR_Xo)*Rez(1),(YT+CenterSR_Yo)*Rez(2),Zo*Rez(3),'EdgeColor','none','FaceColor',[.9 .3 .1]);
                hold on
%                 for i=1:length(Section)
%                     a=round(size(Section{i},1)/2);
%                     plot3(Section{i}(a+1:end,1)+Section{i}(a+1:end,4),Section{i}(a+1:end,2)+Section{i}(a+1:end,5),Section{i}(a+1:end,3),'.','Color',[.2 .9 .2],'MarkerSize',.25),
%                 end
                plot3((ProjectedContour(:,1)+ProjectedContour(:,4))*Rez(1),(ProjectedContour(:,2)+ProjectedContour(:,5))*Rez(2),ProjectedContour(:,3)*Rez(3),'-','Color',[0 1 0],'LineWidth',2)
                surf((XTout+CenterSR_Xo)*Rez(1),(YTout+CenterSR_Yo)*Rez(2),Zo*Rez(3),'EdgeColor','none','FaceColor',[.9 .3 .1],'FaceAlpha',0.2);
                plot3((ProjectedContourOut(:,1)+ProjectedContourOut(:,4))*Rez(1),(ProjectedContourOut(:,2)+ProjectedContourOut(:,5))*Rez(2),ProjectedContourOut(:,3)*Rez(3),'-','Color',[0 0 1],'LineWidth',2)
                axis equal
                camlight;  
                lighting phong;
                lightangle(-145, 30)
                lightangle(35, 30)

                % Calculate inertia tensor
                Aux=[];
                for is=1:length(Sectionin)
                    Aux=[Aux;unique(Sectionin{is}(:,1:3),'row','stable')];
                end
                Aux(:,1)=(Aux(:,1)-mean(Aux(:,1)))*Rez(1);
                Aux(:,2)=(Aux(:,2)-mean(Aux(:,2)))*Rez(2);
                Aux(:,3)=(Aux(:,3)-mean(Aux(:,3)))*Rez(3);
                Inertia=[[sum(Aux(:,2).^2+Aux(:,3).^2) -sum(Aux(:,1).*Aux(:,2)) -sum(Aux(:,1).*Aux(:,3))];...
                         [-sum(Aux(:,1).*Aux(:,2)) sum(Aux(:,1).^2+Aux(:,3).^2)  -sum(Aux(:,2).*Aux(:,3))];...
                         [-sum(Aux(:,1).*Aux(:,3)) -sum(Aux(:,2).*Aux(:,3)) sum(Aux(:,1).^2+Aux(:,2).^2)]];
                [EV,D] = eig(Inertia);
                Ii=find(diag(D)==min(diag(D)));
                MD=mean(diag(D));
                FA=sqrt(sum((diag(D)-MD).^2)/sum(diag(D).^2)*3/2);
                Theta=acos(EV(:,Ii)'*Comp(:,1));
                if abs(Theta)>pi/2
                    Theta=pi-Theta;
                end
                Theta=Theta*180/pi;
                % Axial length along the inner aorta wall
                Xaux=XT(1:end-2,min(ProjectedContour(:,3)):max(ProjectedContour(:,3)))*app.CurrentData.Resolution(1);
                Yaux=YT(1:end-2,min(ProjectedContour(:,3)):max(ProjectedContour(:,3)))*app.CurrentData.Resolution(2);
                Zaux=Zo(1:end-2,min(ProjectedContour(:,3)):max(ProjectedContour(:,3)))*app.CurrentData.Resolution(3);
                AxialLengthAorta=mean(sum(sqrt((Xaux(:,1:end-1)-Xaux(:,2:end)).^2+(Yaux(:,1:end-1)-Yaux(:,2:end)).^2+...
                    (Zaux(:,1:end-1)-Zaux(:,2:end)).^2),2));
                % Calculate cell perimeter
                Perimeter=sum(sqrt(sum((ProjectedContour(1:end-1,1:3)-ProjectedContour(2:end,1:3)).^2,2)));
                % Calculation width
                Width=max(Area/app.CurrentData.Resolution(3));
                % Calculate elongation
                Elongation=AxialLengthAorta/Width;
                
                countE=countE+1;
                
                CellData = [ij...
                    (max(ProjectedContour(:,3))-min(ProjectedContour(:,3)))*app.CurrentData.Resolution(3),...
                    Width,Theta,sum(Area),sum(Volume),MD,FA,D(1,1), D(2,2), D(3,3), AxialLengthAorta, Perimeter, Elongation]
                writematrix(CellData, ExcelFile, 'Sheet', 1, 'Range', ['A' num2str(countE)]);

            end
            
            % Finally produde a fiure wit all the cells
            figure,
            surf((XT+CenterSR_Xo)*Rez(1),(YT+CenterSR_Yo)*Rez(2),Zo*Rez(3),'EdgeColor','none','FaceColor',[.9 .3 .1]);
            hold on
            surf((XTout+CenterSR_Xo)*Rez(1),(YTout+CenterSR_Yo)*Rez(2),Zo*Rez(3),'EdgeColor','none','FaceColor',[.9 .3 .1],'FaceAlpha',0.2);
            for i=1:size(ProjectedCellAll,1)
                plot3((ProjectedCellAll{i,1}(:,1)+ProjectedCellAll{i,1}(:,4))*Rez(1),(ProjectedCellAll{i,1}(:,2)+ProjectedCellAll{i,1}(:,5))*Rez(2),ProjectedCellAll{i,1}(:,3)*Rez(3),'.','Color',[.2 .9 .2]),
            end
            axis equal
            camlight;  
            lighting phong;
            lightangle(-145, 30)
            lightangle(35, 30)
                                    
            % And exprott the data of the aorta
            % Calculate turtuosity
            CenterFaux=CenterForig(:,1:3);
            % CenterFaux=CenterFaux.*repmat(app.CurrentData.Resolution',[nSlices 1]);
            
            % Tortuoisity=sum(sqrt(sum((CenterFaux(1:end-1,1:3)-CenterFaux(2:end,1:3)).^2,2)))/nSlices/app.CurrentData.Resolution(3);
            Tortuoisity = mean(sqrt(sum(((CenterFaux(:,1:2)-CenterSRorig(:,1:2)).*Rez(1:2)').^2,2))); % Bo Shen

            % Add the excel for the aorta
            Diameter=PolarTum(1:round(length(AngleI)/2)-1,:)+PolarTum(round(length(AngleI)/2):end-2,:);
            % Calculate caliber
            Caliber=sum(PolarTum(1:round(length(AngleI)/2)-1,:).^2+PolarTum(round(length(AngleI)/2):end-2,:).^2)/2*sin(AngleI(2)-AngleI(1));
            
            HeaderAorta={'Center X (um)','Center Y (um)','Center Z (um)',...
                'Mean diameter (um)','Maximum diameter (um)','Minimum diameter (um)','Caliber (um2)'...
                'Tortuosity','Average mean diameter all slices (um)',...
                'Std mean diameter all slices (um)','Average Max Diameter all slices (um)',...
                'Std max diameter all slices (um)','Average Min Diameter all slices (um),'...
                'Std min diameter all slices (um)','Aorta axis X','Aorta Axis Y','Aorta Axis Z'};
            ExcelFileAorta=fullfile(app.CurrentData.Directory, [app.CurrentData.FileSaved(1:end-4) app.Version '_AortaData.xlsx']);
            countE=1;
            writecell(HeaderAorta, ExcelFileAorta, 'Sheet', 1, 'Range', ['A' num2str(countE)]);
            CellData = [CenterFaux, mean(Diameter)'  max(Diameter)'  min(Diameter)' Caliber'];
            writematrix(CellData, ExcelFileAorta, 'Sheet', 1, 'Range', ['A2:G2' num2str(nSlices)]);
%             xlswrite(ExcelFileAorta,HeaderAorta,1,'A1');  
%             xlswrite(ExcelFileAorta,[CenterFaux, mean(Diameter)'  max(Diameter)'  min(Diameter)' Caliber'],1,['A2:G' num2str(nSlices+1)])
            
            countE=countE+1;
            CellData = [Tortuoisity mean(mean(Diameter)) std(mean(Diameter)),...
                mean(max(Diameter)) std(max(Diameter))  mean(min(Diameter)) std(min(Diameter)) Comp(1) Comp(2) Comp(3)];
            writematrix(CellData, ExcelFileAorta, 'Sheet', 1, 'Range', ['H2:Q2' num2str(countE)]);
            toc


%% OLD CODE KEEP UNIL NEW IS VALIDATED            % 
%             app.CurrentData.Flags.IsSaved=0;
%             % Lets work first on the data of ROI1
%             GTh=[4*pi/512; 5]; % Thickness around the line that includes the points
%             
%             ShouldIAskForResolution=0;
%             if isempty(app.CurrentData.Resolution)
%                 ShouldIAskForResolution=1;
%             elseif sum(abs(app.CurrentData.Resolution-[1 1 1]))==0    
%                 ShouldIAskForResolution=1;
%             end
%             if ShouldIAskForResolution
%                 answer = inputdlg({'Resolution X (\mu m)','Resolution Y (\mu m)','Resolution Z (\mu m)'},'Enter Resolution',[1 35],{'1','1','1'});
%                 app.CurrentData.Resolution=str2double(answer);
%                 app.CurrentData.Resolution=app.CurrentData.Resolution([1 3 2]); % This is to be consistent with the permutation of hte dataset
%             end
%             
%             CWalls=[];
%             Alpha=app.CurrentData.Alpha;
%             Centers=app.CurrentData.Centers;
%             Spoly=app.CurrentData.Polygon;
%             
%             if max(Alpha(:,1))<=2*pi
%                 Alpha=[Alpha;Alpha];
%                 Alpha(size(Alpha,1)/2+1:end,1)=Alpha(size(Alpha,1)/2+1:end,1)+2*pi;
%             end
%             
%             for ij=1:length(app.CurrentData.ROI)
%                 P=[];
%                 ROI1c=app.CurrentData.ROI{ij};
%                 ROI1c=[ROI1c;ROI1c(1,:)]; % Make it a closed polygone
%                 Rad=4; % Sphere of radious 1.5 around the line twhere points are going to be asigned to the boundary
%                 %ROI1c(:,1)=(ROI1c(:,1)-floor(ROI1c(:,1)/2/pi)*2*pi)/GTh(1); % Remove the duplicity and and normalize for your unit
%                 ROI1c(:,1)=ROI1c(:,1)/GTh(1); % Remove the duplicity and and normalize for your unit
%                 ROI1c(:,2)=ROI1c(:,2)/GTh(2);% Normalize for thickness to 
%                 AlphaAux=Alpha(:,1:2); % Alpha Aux will be normalized and use to rotate
%                 AlphaAux(:,1)=AlphaAux(:,1)/GTh(1);
%                 AlphaAux(:,2)=AlphaAux(:,2)/GTh(2);
%                 for i=1:size(ROI1c,1)-1
%                     m=(ROI1c(i+1,2)-ROI1c(i,2))/(ROI1c(i+1,1)-ROI1c(i,1)); % Find the direction of the 
%                     Th=atan(-1/m);
%                     RM=[cos(Th) sin(Th);-sin(Th) cos(Th)];
%                     AlphaR=(RM*AlphaAux')';
%                     X=(RM*ROI1c(i:i+1,:)')';
%                     P=[P;Alpha(AlphaR(:,1)<=max(X(:,1))+Rad&...
%                                AlphaR(:,1)>=min(X(:,1))-Rad&...
%                                AlphaR(:,2)<=max(X(:,2))+Rad&...
%                                AlphaR(:,2)>=min(X(:,2))-Rad,:)];
%                 end
%                 CWalls=[CWalls;[P(:,[3 4 2]) ij*ones(size(P,1),1)]];
%             end
%             clear ROI1c
%             
%             %% Now back project the data in 3D
%             if max(Alpha(:,1))>2*pi
%                 Alpha=Alpha(1:size(Alpha,1)/2,:);
%             end
%             
%             
%             %% Plot and extract information from all the cells
%             % First calculate thedirection of the artery as the vector
%             % between the begining and end of the vessel. We take three
%             % differences in case there is some wrong values
%             ArteryDirection=median(app.CurrentData.Centers(end-3:end,:)-app.CurrentData.Centers(1:4,:));
%             ArteryDirection=ArteryDirection([2 3 1])'.*app.CurrentData.Resolution;
%             ArteryDirection=ArteryDirection/norm(ArteryDirection);
%             % Prepare the excell file
%             Header={'Cell ID','Height (um)','Widht (um)','Largest length (um)','Angle with artery (deg)','Cell area Proejected (R=20, um^2)','Mean Inertia (um^2)','Anisotropy Inertia'};
%             ExcelFile=[app.CurrentData.Directory app.CurrentData.FileSaved(1:end-4) app.Version '.xlsx'];
%             count=1;
%             xlswrite(ExcelFile,Header,1,['A' num2str(count)]); 
% 
%             for i=unique(CWalls(:,4))'
%                 figure
%                 hold on
%                 for j=min(CWalls(CWalls(:,4)==i,3)):3:max(CWalls(CWalls(:,4)==i,3))
%                     K=length(Spoly{j,1});
%                     DistanceToCenter=1e6*ones(K,1);
%                     for k=1:K
%                         DistanceToCenter(k)=mean(sqrt((Spoly{j,1}{k}(:,1)-Centers(j,2)).^2+(Spoly{j,1}{k}(:,2)-Centers(j,3)).^2));
%                     end
%                     for k=1:K
%                         if k==find(DistanceToCenter==min(DistanceToCenter),1,'last')
%                             plot3(Spoly{j,1}{k}(:,1),Spoly{j,1}{k}(:,2),2.21*j*ones(size(Spoly{j,1}{k}(:,2))),'Color',[.5 .5 .5],'LineWidth',1);
%                         else
%                             plot3(Spoly{j,1}{k}(:,1),Spoly{j,1}{k}(:,2),2.21*j*ones(size(Spoly{j,1}{k}(:,2))),'Color',[.8 .8 .8],'LineWidth',.25);
%                         end
%                     end
%                 end
%                 plot3(Alpha(Alpha(:,2)>=min(CWalls(CWalls(:,4)==i,3))&Alpha(:,2)<=max(CWalls(CWalls(:,4)==i,3)),4),...
%                     Alpha(Alpha(:,2)>=min(CWalls(CWalls(:,4)==i,3))&Alpha(:,2)<=max(CWalls(CWalls(:,4)==i,3)),3),...
%                     Alpha(Alpha(:,2)>=min(CWalls(CWalls(:,4)==i,3))&Alpha(:,2)<=max(CWalls(CWalls(:,4)==i,3)),2)*2.21,'.','Color',[0.4 0.4 0.8])%,'MarkerSize',1)
%                 plot3(CWalls(CWalls(:,4)==i,2),CWalls(CWalls(:,4)==i,1),2.21*CWalls(CWalls(:,4)==i,3),'.','Color',[0.9 0.2 0.2])
%                 view(100,20)
%                 axis equal
%                 X=CWalls(CWalls(:,4)==i,2);
%                 X=X-mean(X);
%                 Y=CWalls(CWalls(:,4)==i,1);
%                 Y-Y-mean(Y);
%                 Z=2.21*CWalls(CWalls(:,4)==i,3);
%                 Z=Z-mean(Z);
%                 Inertia=[[sum(Y.^2+Z.^2) -sum(X.*Y) -sum(X.*Z)];[-sum(X.*Y) sum(X.^2+Z.^2)  -sum(Y.*Z)];[-sum(X.*Z) -sum(Y.*Z) sum(X.^2+Y.^2)]];
%                 [EV,D] = eig(Inertia);
%                 MD=mean(diag(D));
%                 FA=sqrt(sum((diag(D)-MD).^2)/sum(diag(D).^2)*3/2);
%                 
%                 % Claculate the maximum separation
%                 Xa=repmat(X,[1 length(X)]);Xa=(Xa-Xa')*app.CurrentData.Resolution(1);
%                 Ya=repmat(Y,[1 length(Y)]);Ya=(Ya-Ya')*app.CurrentData.Resolution(2);
%                 Za=repmat(Z,[1 length(Z)]);Za=(Za-Za')*app.CurrentData.Resolution(3);
%                 D2=sqrt(Xa.^2+Ya.^2);
%                 D3=triu(sqrt(Xa.^2+Ya.^2+Za.^2));
%                 % Orientation of the longest direction witht he axis of the
%                 % artery
%                 I=find(D3>prctile(D3(D3>0),99.99)); % Take the 0.001% of voxels THIS MAY BE NOT GOOD FOR SMALL CELS
%                 R=[mean(Xa(I)) mean(Ya(I)) mean(Za(I))];
%                 r=R/norm(R);
%                 Theta=    
%                 if Theta>pi/2
%                     Theta=pi-Theta;
%                 end
%                 Theta=Theta*180/pi;
%                 % We calculate the area in a normalized distance of 20 for
%                 % all the arteries i s not perfect but it is wat it is
%                 ROIareaP=polyarea(20*app.CurrentData.Resolution(1)*app.CurrentData.ROI{i}(:,1),app.CurrentData.Resolution(3)*app.CurrentData.ROI{i}(:,2));
%                 disp('klklkl')                
%                 title(sprintf('Height (um) = %.2f\nWidth (um) = %.2f\nLargest length (um) = %.2f\nAngle with Artery (deg)= %.2f\nCell area projected (R=20, um^2) %.2f\nMean I = %1.2g\nAnisotropy I = %.2f.',...
%                     (max(Z)-min(Z))*app.CurrentData.Resolution(3),max(D2(:)),max(D3(:)),Theta,ROIareaP,MD,FA) )   
%                 count=count+1;
%                 xlswrite(ExcelFile,[i (max(Z)-min(Z))*app.CurrentData.Resolution(3),max(D2(:)),max(D3(:)),Theta,ROIareaP,MD,FA],1,['A' num2str(count)])
%             end
%                 
%             
% 
%         
%     
        end

        % Button pushed function: NewCellButton
        function NewCellButtonPushed(app, event)
            count=length(app.CurrentData.ROI)+1;
            app.CurrentData.Temp{3}(count)= drawpolygon(app.CurrentData.Temp{2});
            %app.CurrentData.ROI{end+1}=app.CurrentData.Temp{3}(count).Position;            
        end

        % Value changing function: ThresholdGreenKnob
        function ThresholdGreenKnobValueChanging(app, event)
            app.ThresholdGreenKnob.Value = event.Value;
            app.ThresholdEditField.Value=round(10*app.ThresholdGreenKnob.Value)/10;
            app = CalculateProjectionInCylinder(app);
            %Change alpha in the data
            app.CurrentData.Temp{2}.Children(end).XData=app.CurrentData.Alpha(:,1);
            app.CurrentData.Temp{2}.Children(end).YData=app.CurrentData.Alpha(:,2);
            app.CurrentData.Temp{2}.Children(end-1).XData=app.CurrentData.Alpha(:,1)+2*pi;
            app.CurrentData.Temp{2}.Children(end-1).YData=app.CurrentData.Alpha(:,2);
            set(app.CurrentData.Temp{2}.Title, 'String',['Threshold =' blanks(1) num2str(app.ThresholdGreenKnob.Value*1e3)]);

        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            if strcmp(app.ThresholdGreenKnob.Visible,'on')
                app.ThresholdGreenKnob.Value=app.ThresholdEditField.Value;
                cla(app.UIAxes)
                DisplayRGBimage(app);            
            end
            if strcmp(app.ThresholdRedKnob.Visible,'on')
                app.ThresholdRedKnob.Value=app.ThresholdEditField.Value;
                app=CalculateCentersAndSegmentation(app);
                cla(app.UIAxes)
                DisplayRGBimage(app);            
            end
            
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            app.RadiusKnob.Value = app.RadiusEditField.Value;     
            cla(app.UIAxes)
            DisplayRGBimage(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.UIFigure);
            app.DatasetDropDown.Items = {'Load new image'};
            app.DatasetDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DatasetDropDownOpening, true);
            app.DatasetDropDown.Value = 'Load new image';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.ArterySelectionButton = uibutton(app.UIFigure, 'push');
            app.ArterySelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ArterySelectionButtonPushed, true);
            app.ArterySelectionButton.Visible = 'off';
            app.ArterySelectionButton.Text = 'Artery Selection';
            app.SegmentationButton = uibutton(app.UIFigure, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Visible = 'off';
            app.SegmentationButton.Text = 'Segmentation';
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Visible = 'off';
            app.EndButton.Text = 'End';
            app.CylinderButton = uibutton(app.UIFigure, 'push');
            app.CylinderButton.ButtonPushedFcn = createCallbackFcn(app, @CylinderButtonPushed, true);
            app.CylinderButton.Visible = 'off';
            app.CylinderButton.Text = 'Cylinder';
            app.CellsStatsButton = uibutton(app.UIFigure, 'push');
            app.CellsStatsButton.ButtonPushedFcn = createCallbackFcn(app, @CellsStatsButtonPushed, true);
            app.CellsStatsButton.Visible = 'off';
            app.CellsStatsButton.Text = 'Cells/Stats';
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Visible = 'off';
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.Limits = [1 1024];
            app.SliceSlider.MajorTicks = [1 102 205 307 410 512 614 717 819 922 1024];
            app.SliceSlider.MajorTickLabels = {'1', '102', '205', '307', '410', '512', '614', '717', '819', '922', '1024'};
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.Visible = 'off';
            app.SliceSlider.Value = 1;
            app.RadiusKnobLabel = uilabel(app.UIFigure);
            app.RadiusKnobLabel.Visible = 'off';
            app.RadiusKnobLabel.Text = 'Radius';
            app.RadiusKnob = uiknob(app.UIFigure, 'continuous');
            app.RadiusKnob.ValueChangingFcn = createCallbackFcn(app, @RadiusKnobValueChanging, true);
            app.RadiusKnob.Visible = 'off';
            app.RadiusKnob.Value = 35;
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.Visible = 'off';
            app.CloseButton.Text = 'Close';
            app.ThresholdRedKnobLabel = uilabel(app.UIFigure);
            app.ThresholdRedKnobLabel.Visible = 'off';
            app.ThresholdRedKnobLabel.Text = 'Threshold Red';
            app.ThresholdRedKnob = uiknob(app.UIFigure, 'continuous');
            app.ThresholdRedKnob.Limits = [0 65];
            app.ThresholdRedKnob.MajorTicks = [0 10 20 30 40 50 60];
            app.ThresholdRedKnob.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60'};
            app.ThresholdRedKnob.ValueChangedFcn = createCallbackFcn(app, @ThresholdRedKnobValueChanged, true);
            app.ThresholdRedKnob.MinorTicks = [5 15 25 35 45 55 65];
            app.ThresholdRedKnob.Visible = 'off';
            app.ThresholdRedKnob.Value = 6;
            app.ThresholdGreenKnobLabel = uilabel(app.UIFigure);
            app.ThresholdGreenKnobLabel.Visible = 'off';
            app.ThresholdGreenKnobLabel.Text = 'Threshold Green';
            app.ThresholdGreenKnob = uiknob(app.UIFigure, 'continuous');
            app.ThresholdGreenKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ThresholdGreenKnob.MajorTickLabels = {'0', '6', '12', '18', '24 ', '30', '36', '42', '48', '54', '60'};
            app.ThresholdGreenKnob.ValueChangingFcn = createCallbackFcn(app, @ThresholdGreenKnobValueChanging, true);
            app.ThresholdGreenKnob.MinorTicks = [5 15 25 35 45 55 65 75 85 95];
            app.ThresholdGreenKnob.Visible = 'off';
            app.ThresholdGreenKnob.Value = 50;
            app.CenterRedSliderLabel = uilabel(app.UIFigure);
            app.CenterRedSliderLabel.Visible = 'off';
            app.CenterRedSliderLabel.Text = {'Center'; 'Red'};
            app.CenterRedSlider = uislider(app.UIFigure);
            app.CenterRedSlider.ValueChangingFcn = createCallbackFcn(app, @CenterRedSliderValueChanging, true);
            app.CenterRedSlider.Visible = 'off';
            app.CenterRedSlider.Value = 20;
            app.WindowRedSliderLabel = uilabel(app.UIFigure);
            app.WindowRedSliderLabel.Visible = 'off';
            app.WindowRedSliderLabel.Text = {'Window'; 'Red'};
            app.WindowRedSlider = uislider(app.UIFigure);
            app.WindowRedSlider.ValueChangingFcn = createCallbackFcn(app, @WindowRedSliderValueChanging, true);
            app.WindowRedSlider.Visible = 'off';
            app.WindowRedSlider.Value = 40;
            app.CenterGreenSliderLabel = uilabel(app.UIFigure);
            app.CenterGreenSliderLabel.Visible = 'off';
            app.CenterGreenSliderLabel.Text = {'Center'; 'Green'};
            app.CenterGreenSlider = uislider(app.UIFigure);
            app.CenterGreenSlider.ValueChangingFcn = createCallbackFcn(app, @CenterGreenSliderValueChanging, true);
            app.CenterGreenSlider.Visible = 'off';
            app.CenterGreenSlider.Value = 20;
            app.WindowGreenSliderLabel = uilabel(app.UIFigure);
            app.WindowGreenSliderLabel.Visible = 'off';
            app.WindowGreenSliderLabel.Text = {'Window'; 'Green'};
            app.WindowGreenSlider = uislider(app.UIFigure);
            app.WindowGreenSlider.ValueChangingFcn = createCallbackFcn(app, @WindowGreenSliderValueChanging, true);
            app.WindowGreenSlider.Visible = 'off';
            app.WindowGreenSlider.Value = 40;
            app.NewCellButton = uibutton(app.UIFigure, 'push');
            app.NewCellButton.ButtonPushedFcn = createCallbackFcn(app, @NewCellButtonPushed, true);
            app.NewCellButton.Visible = 'off';
            app.NewCellButton.Text = 'New Cell';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Visible = 'off';
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Visible = 'off';
            app.RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusEditFieldLabel.Visible = 'off';
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.RadiusEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellSegmentationApp_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end